classdef INVERSEKINEMATICSJACOB_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1   matlab.ui.Figure
        uipanel1  matlab.ui.container.Panel
        T1        matlab.ui.control.EditField
        T2        matlab.ui.control.EditField
        T3        matlab.ui.control.EditField
        text2     matlab.ui.control.Label
        Update    matlab.ui.control.Button
        LN        matlab.ui.control.EditField
        text3     matlab.ui.control.Label
        Reset     matlab.ui.control.Button
        uipanel2  matlab.ui.container.Panel
        uitable2  matlab.ui.control.Table
        uipanel3  matlab.ui.container.Panel
        ANS       matlab.ui.control.Label
        uipanel4  matlab.ui.container.Panel
        text5     matlab.ui.control.Label
        TN        matlab.ui.control.EditField
        V1        matlab.ui.control.EditField
        V2        matlab.ui.control.EditField
        V3        matlab.ui.control.EditField
        text6     matlab.ui.control.Label
        text7     matlab.ui.control.Label
        text8     matlab.ui.control.Label
    end

    
    methods (Access = private)
        function T4_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to T4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function T5_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to T5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function T6_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to T6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function V4_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to V4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function V5_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to V5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function V6_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to V6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function INVERSEKINEMATICSJACOB_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to INVERSEKINEMATICSJACOB (see VARARGIN)
%             
%             % Choose default command line output for INVERSEKINEMATICSJACOB
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Button pushed function: Reset
        function Reset_Callback(app, event)
            % hObject    handle to Reset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            (set(app.LN,'string',0));
            (set(app.TN,'string',0));
            (set(app.T1,'string',0));
            (set(app.T2,'string',0));
            (set(app.T3,'string',0));
            (set(app.V1,'string',0));
            (set(app.V2,'string',0));
            (set(app.V3,'string',0));
            set(app.ANS,'string', [ ]);
             set(app.uitable2,'data',[0;0; 0]);
        end

        % Button pushed function: Update
        function Update_Callback(app, event)
            % hObject    handle to Update (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            i=str2double(get(app.LN,'string'));
            n=str2double(get(app.TN,'string'));
            app.T1=str2double(get(app.T1,'string'));
            app.T2=str2double(get(app.T2,'string'));
            app.T3=str2double(get(app.T3,'string'));
            
            app.V1=str2double(get(app.V1,'string'));
            app.V2=str2double(get(app.V2,'string'));
            app.V3=str2double(get(app.V3,'string'));
            
            V=[app.V1;app.V2;app.V3];
            U=[app.T1;app.T2;app.T3];
            J=get(app.uitable2,'data');
            % if i==1
            %     J(6,i)=[0;0;0;0;0;0]
            J(:,i)=U;
            set(app.uitable2,'data',J);
            if i==n
                q=pinv(J)*V;
                for j=1:n
            
                         outputString(j)=cellstr(sprintf('q %d = %0.2f \n',j,q(j)));
            
            
                end
                set(app.ANS,'string', outputString);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'INVERSEKINEMATICSJACOB';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Link Jacobian';
            app.T1 = uieditfield(app.uipanel1, 'text');
            app.T1.Value = '0';
            app.T2 = uieditfield(app.uipanel1, 'text');
            app.T2.Value = '0';
            app.T3 = uieditfield(app.uipanel1, 'text');
            app.T3.Value = '0';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Text = 'Linear Part of Jacobian';
            app.Update = uibutton(app.uipanel1, 'push');
            app.Update.ButtonPushedFcn = createCallbackFcn(app, @Update_Callback, true);
            app.Update.Text = 'Update';
            app.LN = uieditfield(app.uipanel1, 'text');
            app.LN.Value = '1';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Text = 'Link Number';
            app.Reset = uibutton(app.uipanel1, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @Reset_Callback, true);
            app.Reset.Text = 'Reset';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Jacobian';
            app.uitable2 = uitable(app.uipanel2);
            app.uitable2.ColumnName = {'1'; '2'};
            app.uitable2.ColumnEditable = true;
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Joint Variables';
            app.ANS = uilabel(app.uipanel3);
            app.ANS.Text = '';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Initial Parameters';
            app.text5 = uilabel(app.uipanel4);
            app.text5.Text = 'Total number of links';
            app.TN = uieditfield(app.uipanel4, 'text');
            app.TN.Value = '0';
            app.V1 = uieditfield(app.uipanel4, 'text');
            app.V1.Value = '0';
            app.V2 = uieditfield(app.uipanel4, 'text');
            app.V2.Value = '0';
            app.V3 = uieditfield(app.uipanel4, 'text');
            app.V3.Value = '0';
            app.text6 = uilabel(app.uipanel4);
            app.text6.Text = 'Px';
            app.text7 = uilabel(app.uipanel4);
            app.text7.Text = 'Py';
            app.text8 = uilabel(app.uipanel4);
            app.text8.Text = 'Pz';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = INVERSEKINEMATICSJACOB_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @INVERSEKINEMATICSJACOB_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end