classdef Transform_mapping_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TransformOperator               matlab.ui.Figure
        AATable                         matlab.ui.control.Table
        PositionVectorFrameBwrtAPanel_2  matlab.ui.container.Panel
        XPositionEditFieldLabel         matlab.ui.control.Label
        XPositionEditField              matlab.ui.control.NumericEditField
        YPositionEditFieldLabel         matlab.ui.control.Label
        YPositionEditField              matlab.ui.control.NumericEditField
        ZPositionEditFieldLabel         matlab.ui.control.Label
        ZPositionEditField              matlab.ui.control.NumericEditField
        TransformVectorButton           matlab.ui.control.Button
        TMTable                         matlab.ui.control.Table
        NumberofRotationEditFieldLabel  matlab.ui.control.Label
        NumberofRotationEditField       matlab.ui.control.NumericEditField
        RotationNoEditFieldLabel        matlab.ui.control.Label
        RotationNoEditField             matlab.ui.control.NumericEditField
        AngleEditFieldLabel             matlab.ui.control.Label
        AngleEditField                  matlab.ui.control.NumericEditField
        AxisEditFieldLabel              matlab.ui.control.Label
        AxisEditField                   matlab.ui.control.EditField
        UpdateButton                    matlab.ui.control.Button
        VectorinFrameBPanel             matlab.ui.container.Panel
        XPositionEditField_2Label       matlab.ui.control.Label
        XPositionEditField_2            matlab.ui.control.NumericEditField
        YPositionEditField_2Label       matlab.ui.control.Label
        YPositionEditField_2            matlab.ui.control.NumericEditField
        ZPositionEditField_2Label       matlab.ui.control.Label
        ZPositionEditField_2            matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
         
        T={} % Description
        A={} % Description   
  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TransformVectorButton
        function TransformVectorButtonPushed(app, event)
  close all
NRT= app.NumberofRotationEditField.Value;
EEPX=app.XPositionEditField.Value;
EEPY=app.YPositionEditField.Value;
EEPZ=app.ZPositionEditField.Value;
EEPXAB=app.XPositionEditField_2.Value;
EEPYAB=app.YPositionEditField_2.Value;
EEPZAB=app.ZPositionEditField_2.Value;
syms x y z 
theta=ones(NRT,1);
r=eye(3);
rp=eye(3);
P0=zeros(1,3);
for i=1:NRT   
theta(i)=cell2mat(app.T(i));
Axis(i)=app.A(i);
k(i)=app.A(i);
if k(i)==x
    k1=1;
    k2=0;
    k3=0;
end
if k(i)==y
    k1=0;        
    k2=1;
    k3=0;
end
if k(i)==z
    k1=0;
    k2=0;
    k3=1;
end

st=sind(theta(i));
ct=cosd(theta(i));
vt=(1-ct);

r1=[k1^2*vt+ct        k1*k2*vt-k3*st    k1*k3*vt+k2*st ;
   k1*k2*vt+k3*st    k2^2*vt+ct        k2*k3*vt-k1*st ;
   k1*k3*vt-k2*st    k2*k3*vt+k1*st    k3^2*vt+ct ];

eval(sprintf('R%d = r1;',i));
Rr=r1;
eval(sprintf('R%d',i))


eval(sprintf('R%d = r1;',i));
r=r1'*r;
eval(sprintf('R%d',i))

eval(sprintf('Rp%d = r1;',i));
rp=rp*r1;
eval(sprintf('Rp%d',i))

Q1=rotm2quat(rp);
 eval(sprintf('QD%d = Q1;',i));
  q=Q1;
 eval(sprintf('QD%d',i))

 end
grid on
plotTransforms(P0,[1 0 0 0])
hold on
x1=[0 EEPX];
y1=[0 EEPY];
z1=[0 EEPZ];
plot3(x1, y1, z1,'O--') 
plotTransforms([EEPX EEPY EEPZ],q)
e=[rp, [EEPX;EEPY;EEPZ]; 0 0 0 1];
xlabel('X-axis Red')
ylabel('Y-axis Green')
zlabel('Z-axis Blue')
P=[EEPXAB EEPYAB EEPZAB 1];
Pf=e*P';
plot3([EEPX Pf(1)], [EEPY Pf(2)],[EEPZ Pf(3)],'b--')
plot3([0 Pf(1)], [0 Pf(2)],[0 Pf(3)],'r--')
legend('Vector A', 'Transform Vector A')

app.TMTable.Data=Pf(1:3);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
 

i=app.RotationNoEditField.Value;
t={app.AngleEditField.Value};
a={app.AxisEditField.Value};
app.T(i)= [t];
app.A(i)= (a);
x=[app.A' app.T'];
app.AATable.Data=x;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TransformOperator = uifigure('Visible', 'off');
            app.TransformOperator.Name = 'Transform Operator';
            app.AATable = uitable(app.TransformOperator);
            app.AATable.ColumnName = {'Axis'; 'Angle'};
            app.AATable.RowName = {};
            app.PositionVectorFrameBwrtAPanel_2 = uipanel(app.TransformOperator);
            app.PositionVectorFrameBwrtAPanel_2.Title = 'Position Vector Frame B  w.r.t A';
            app.XPositionEditFieldLabel = uilabel(app.PositionVectorFrameBwrtAPanel_2);
            app.XPositionEditFieldLabel.Text = 'X-Position';
            app.XPositionEditField = uieditfield(app.PositionVectorFrameBwrtAPanel_2, 'numeric');
            app.YPositionEditFieldLabel = uilabel(app.PositionVectorFrameBwrtAPanel_2);
            app.YPositionEditFieldLabel.Text = 'Y-Position';
            app.YPositionEditField = uieditfield(app.PositionVectorFrameBwrtAPanel_2, 'numeric');
            app.ZPositionEditFieldLabel = uilabel(app.PositionVectorFrameBwrtAPanel_2);
            app.ZPositionEditFieldLabel.Text = 'Z-Position';
            app.ZPositionEditField = uieditfield(app.PositionVectorFrameBwrtAPanel_2, 'numeric');
            app.TransformVectorButton = uibutton(app.TransformOperator, 'push');
            app.TransformVectorButton.ButtonPushedFcn = createCallbackFcn(app, @TransformVectorButtonPushed, true);
            app.TransformVectorButton.Text = 'Transform Vector';
            app.TMTable = uitable(app.TransformOperator);
            app.TMTable.ColumnName = {'Translational Matrix (T12)'};
            app.TMTable.RowName = {};
            app.NumberofRotationEditFieldLabel = uilabel(app.TransformOperator);
            app.NumberofRotationEditFieldLabel.Text = 'Number of Rotation';
            app.NumberofRotationEditField = uieditfield(app.TransformOperator, 'numeric');
            app.RotationNoEditFieldLabel = uilabel(app.TransformOperator);
            app.RotationNoEditFieldLabel.Text = 'Rotation No.';
            app.RotationNoEditField = uieditfield(app.TransformOperator, 'numeric');
            app.AngleEditFieldLabel = uilabel(app.TransformOperator);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.TransformOperator, 'numeric');
            app.AxisEditFieldLabel = uilabel(app.TransformOperator);
            app.AxisEditFieldLabel.Text = 'Axis';
            app.AxisEditField = uieditfield(app.TransformOperator, 'text');
            app.UpdateButton = uibutton(app.TransformOperator, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.VectorinFrameBPanel = uipanel(app.TransformOperator);
            app.VectorinFrameBPanel.Title = 'Vector in Frame B';
            app.XPositionEditField_2Label = uilabel(app.VectorinFrameBPanel);
            app.XPositionEditField_2Label.Text = 'X-Position';
            app.XPositionEditField_2 = uieditfield(app.VectorinFrameBPanel, 'numeric');
            app.YPositionEditField_2Label = uilabel(app.VectorinFrameBPanel);
            app.YPositionEditField_2Label.Text = 'Y-Position';
            app.YPositionEditField_2 = uieditfield(app.VectorinFrameBPanel, 'numeric');
            app.ZPositionEditField_2Label = uilabel(app.VectorinFrameBPanel);
            app.ZPositionEditField_2Label.Text = 'Z-Position';
            app.ZPositionEditField_2 = uieditfield(app.VectorinFrameBPanel, 'numeric');
            % Show the figure after all components are created
            app.TransformOperator.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Transform_mapping_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TransformOperator)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TransformOperator)
        end
    end
end