classdef Trial1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LinkNo                       matlab.ui.control.NumericEditField
        thetaInput                   matlab.ui.control.NumericEditField
        dInput                       matlab.ui.control.NumericEditField
        aInput                       matlab.ui.control.NumericEditField
        alphaInput                   matlab.ui.control.NumericEditField
        DHUpdate                     matlab.ui.control.Button
        DHTable                      matlab.ui.control.Table
        NumberofLinksEditFieldLabel  matlab.ui.control.Label
        TotalLinks                   matlab.ui.control.NumericEditField
        valueofa2EditFieldLabel      matlab.ui.control.Label
        Check                        matlab.ui.control.EditField
    end

    
    properties (Access = private)
        a=[]
        alpha=[]
        theta=[]
        d=[]
        
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Check, DHTable, DHUpdate
        function DHUpdateButtonPushed(app, event)
            n = app.TotalLinks.Value;
            i = app.LinkNo.Value;
            app.theta(i) = app.thetaInput.Value;
            app.d(i) = app.dInput.Value;
            app.a(i) = app.aInput.Value;
            app.alpha(i) = app.alphaInput.Value;
            
          
                    param(i,:) = [app.theta(i), app.d(i), app.a(i), app.alpha(i)];
                
                app.DHTable.Data = param;     
         
             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LinkNo = uieditfield(app.UIFigure, 'numeric');
            app.thetaInput = uieditfield(app.UIFigure, 'numeric');
            app.dInput = uieditfield(app.UIFigure, 'numeric');
            app.aInput = uieditfield(app.UIFigure, 'numeric');
            app.alphaInput = uieditfield(app.UIFigure, 'numeric');
            app.DHUpdate = uibutton(app.UIFigure, 'push');
            app.DHUpdate.ButtonPushedFcn = createCallbackFcn(app, @DHUpdateButtonPushed, true);
            app.DHUpdate.Text = 'Update';
            app.DHTable = uitable(app.UIFigure);
            app.DHTable.ColumnName = {'Theta'; 'd'; 'a'; 'alpha'};
            app.DHTable.RowName = {};
            app.DHTable.DisplayDataChangedFcn = createCallbackFcn(app, @DHUpdateButtonPushed, true);
            app.NumberofLinksEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofLinksEditFieldLabel.Text = 'Number of Links';
            app.TotalLinks = uieditfield(app.UIFigure, 'numeric');
            app.valueofa2EditFieldLabel = uilabel(app.UIFigure);
            app.valueofa2EditFieldLabel.Text = 'value of a 2';
            app.Check = uieditfield(app.UIFigure, 'text');
            app.Check.ValueChangedFcn = createCallbackFcn(app, @DHUpdateButtonPushed, true);
            app.Check.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Trial1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end