classdef Description_of_Frame_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DescriptionOfFrame              matlab.ui.Figure
        AATable                         matlab.ui.control.Table
        PositionofFrame2WithRepectto1Panel  matlab.ui.container.Panel
        XPositionEditFieldLabel         matlab.ui.control.Label
        XPositionEditField              matlab.ui.control.NumericEditField
        YPositionEditFieldLabel         matlab.ui.control.Label
        YPositionEditField              matlab.ui.control.NumericEditField
        ZPositionEditFieldLabel         matlab.ui.control.Label
        ZPositionEditField              matlab.ui.control.NumericEditField
        CurrentFrameButton              matlab.ui.control.Button
        FixedFrameButton                matlab.ui.control.Button
        TMTable                         matlab.ui.control.Table
        NumberofRotationEditFieldLabel  matlab.ui.control.Label
        NumberofRotationEditField       matlab.ui.control.NumericEditField
        RotationNoEditFieldLabel        matlab.ui.control.Label
        RotationNoEditField             matlab.ui.control.NumericEditField
        AngleEditFieldLabel             matlab.ui.control.Label
        AngleEditField                  matlab.ui.control.NumericEditField
        AxisEditFieldLabel              matlab.ui.control.Label
        AxisEditField                   matlab.ui.control.EditField
        UpdateButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
         
        T={} % Description
        A={} % Description   
  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CurrentFrameButton
        function CurrentFrameButtonPushed(app, event)
            %Translational matrix Specifing axis and angle of rotation%
%Number of Rotational transformation%
NRT= app.NumberofRotationEditField.Value;
EEPX=app.XPositionEditField.Value;
EEPY=app.YPositionEditField.Value;
EEPZ=app.ZPositionEditField.Value;
% NRT=str2double(inputdlg('Number of Rotational Transformation ')); 
syms x y z 
theta=ones(NRT,1);
r=eye(3);
P0=zeros(1,3);
for i=1:NRT   
theta(i)=cell2mat(app.T(i));
Axis(i)=app.A(i);
k(i)=app.A(i);
if k(i)==x
    k1=1;
    k2=0;
    k3=0;
end
if k(i)==y
    k1=0;        
    k2=1;
    k3=0;
end
if k(i)==z
    k1=0;
    k2=0;
    k3=1;
end

st=sind(theta(i));
ct=cosd(theta(i));
vt=(1-ct);

r1=[k1^2*vt+ct        k1*k2*vt-k3*st    k1*k3*vt+k2*st ;
   k1*k2*vt+k3*st    k2^2*vt+ct        k2*k3*vt-k1*st ;
   k1*k3*vt-k2*st    k2*k3*vt+k1*st    k3^2*vt+ct ];

eval(sprintf('R%d = r1;',i));
Rr=r1;
eval(sprintf('R%d',i))


eval(sprintf('R%d = r1;',i));
r=r*r1;
eval(sprintf('R%d',i))

Q1=rotm2quat(r);
 eval(sprintf('QD%d = Q1;',i));
  q=Q1;
 eval(sprintf('QD%d',i))

 end
grid on
plotTransforms(P0,[1 0 0 0])
hold on
x1=[0 EEPX];
y1=[0 EEPY];
z1=[0 EEPZ];
plot3(x1, y1, z1,'O--') 
plotTransforms([EEPX EEPY EEPZ],q)
xlabel('X-axis Red')
ylabel('Y-axis Green')
zlabel('Z-axis Blue')
P=[EEPX EEPY EEPZ];
T=[r P'; 0 0 0 1];
disp(['Translation Matrix from 0 to ',num2str(i)] )
disp(T)
Input_Axis_Angle=cell2table([Axis' num2cell(theta)]);
app.AATable.Data=Input_Axis_Angle;
app.TMTable.Data=T;
        end

        % Button pushed function: FixedFrameButton
        function FixedFrameButtonPushed(app, event)
  %Translational matrix Specifing axis and angle of rotation%
%Number of Rotational transformation%
NRT= app.NumberofRotationEditField.Value;
EEPX=app.XPositionEditField.Value;
EEPY=app.YPositionEditField.Value;
EEPZ=app.ZPositionEditField.Value;
% NRT=str2double(inputdlg('Number of Rotational Transformation ')); 
syms x y z 
theta=ones(NRT,1);
r=eye(3);
P0=zeros(1,3);
for i=1:NRT   
theta(i)=cell2mat(app.T(i));
Axis(i)=app.A(i);
k(i)=app.A(i);
if k(i)==x
    k1=1;
    k2=0;
    k3=0;
end
if k(i)==y
    k1=0;        
    k2=1;
    k3=0;
end
if k(i)==z
    k1=0;
    k2=0;
    k3=1;
end

st=sind(theta(i));
ct=cosd(theta(i));
vt=(1-ct);

r1=[k1^2*vt+ct        k1*k2*vt-k3*st    k1*k3*vt+k2*st ;
   k1*k2*vt+k3*st    k2^2*vt+ct        k2*k3*vt-k1*st ;
   k1*k3*vt-k2*st    k2*k3*vt+k1*st    k3^2*vt+ct ];

eval(sprintf('R%d = r1;',i));
Rr=r1;
eval(sprintf('R%d',i))


eval(sprintf('R%d = r1;',i));
r=r1*r;
eval(sprintf('R%d',i))

Q1=rotm2quat(r);
 eval(sprintf('QD%d = Q1;',i));
  q=Q1;
 eval(sprintf('QD%d',i))

 end
grid on
plotTransforms(P0,[1 0 0 0])
hold on
x1=[0 EEPX];
y1=[0 EEPY];
z1=[0 EEPZ];
plot3(x1, y1, z1,'O--') 
plotTransforms([EEPX EEPY EEPZ],q)
xlabel('X-axis Red')
ylabel('Y-axis Green')
zlabel('Z-axis Blue')
P=[EEPX EEPY EEPZ];
T=[r P'; 0 0 0 1];
disp(['Translation Matrix from 0 to ',num2str(i)] )
disp(T)
Input_Axis_Angle=cell2table([Axis' num2cell(theta)]);
app.AATable.Data=Input_Axis_Angle;
app.TMTable.Data=T;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
 
%NRT=str2double(inputdlg('Number of Rotational Transformation ')); 
%syms x y z 
i=app.RotationNoEditField.Value;
t={app.AngleEditField.Value};
a={app.AxisEditField.Value};
app.T(i)= [t];
app.A(i)= (a);
x=[app.A' app.T'];
app.AATable.Data=x;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DescriptionOfFrame = uifigure('Visible', 'off');
            app.DescriptionOfFrame.Name = 'Description of Frame';
            app.AATable = uitable(app.DescriptionOfFrame);
            app.AATable.ColumnName = {'Axis'; 'Angle'};
            app.AATable.RowName = {};
            app.PositionofFrame2WithRepectto1Panel = uipanel(app.DescriptionOfFrame);
            app.PositionofFrame2WithRepectto1Panel.Title = 'Position of Frame 2 With Repect to 1';
            app.XPositionEditFieldLabel = uilabel(app.PositionofFrame2WithRepectto1Panel);
            app.XPositionEditFieldLabel.Text = 'X-Position';
            app.XPositionEditField = uieditfield(app.PositionofFrame2WithRepectto1Panel, 'numeric');
            app.YPositionEditFieldLabel = uilabel(app.PositionofFrame2WithRepectto1Panel);
            app.YPositionEditFieldLabel.Text = 'Y-Position';
            app.YPositionEditField = uieditfield(app.PositionofFrame2WithRepectto1Panel, 'numeric');
            app.ZPositionEditFieldLabel = uilabel(app.PositionofFrame2WithRepectto1Panel);
            app.ZPositionEditFieldLabel.Text = 'Z-Position';
            app.ZPositionEditField = uieditfield(app.PositionofFrame2WithRepectto1Panel, 'numeric');
            app.CurrentFrameButton = uibutton(app.DescriptionOfFrame, 'push');
            app.CurrentFrameButton.ButtonPushedFcn = createCallbackFcn(app, @CurrentFrameButtonPushed, true);
            app.CurrentFrameButton.Text = 'Current Frame';
            app.FixedFrameButton = uibutton(app.DescriptionOfFrame, 'push');
            app.FixedFrameButton.ButtonPushedFcn = createCallbackFcn(app, @FixedFrameButtonPushed, true);
            app.FixedFrameButton.Text = 'Fixed Frame';
            app.TMTable = uitable(app.DescriptionOfFrame);
            app.TMTable.ColumnName = {'Translational Matrix (T12)'};
            app.TMTable.RowName = {};
            app.NumberofRotationEditFieldLabel = uilabel(app.DescriptionOfFrame);
            app.NumberofRotationEditFieldLabel.Text = 'Number of Rotation';
            app.NumberofRotationEditField = uieditfield(app.DescriptionOfFrame, 'numeric');
            app.RotationNoEditFieldLabel = uilabel(app.DescriptionOfFrame);
            app.RotationNoEditFieldLabel.Text = 'Rotation No.';
            app.RotationNoEditField = uieditfield(app.DescriptionOfFrame, 'numeric');
            app.AngleEditFieldLabel = uilabel(app.DescriptionOfFrame);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.DescriptionOfFrame, 'numeric');
            app.AxisEditFieldLabel = uilabel(app.DescriptionOfFrame);
            app.AxisEditFieldLabel.Text = 'Axis';
            app.AxisEditField = uieditfield(app.DescriptionOfFrame, 'text');
            app.UpdateButton = uibutton(app.DescriptionOfFrame, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.DescriptionOfFrame.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Description_of_Frame_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DescriptionOfFrame)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DescriptionOfFrame)
        end
    end
end