classdef Forward_Kinematics_noDH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DHTable                      matlab.ui.control.Table
        INVERSEKINEMATICS            matlab.ui.control.Label
        AllValuesforAnglesmustbeenteredinDEGREEUnitONLYLabel  matlab.ui.control.Label
        ValueofJointVariablesPanel   matlab.ui.container.Panel
        JointNoEditFieldLabel        matlab.ui.control.Label
        JointNo                      matlab.ui.control.NumericEditField
        ValueEditFieldLabel          matlab.ui.control.Label
        JointVariable                matlab.ui.control.NumericEditField
        TextArea                     matlab.ui.control.TextArea
        RUNButton                    matlab.ui.control.Button
        ValueofTbePanel              matlab.ui.container.Panel
        TransformationTable          matlab.ui.control.Table
        RESETButton                  matlab.ui.control.Button
        LinkDefinations              matlab.ui.container.Panel
        LinkNo                       matlab.ui.control.NumericEditField
        LinkNoLabel                  matlab.ui.control.Label
        NumberofLinksEditFieldLabel  matlab.ui.control.Label
        TotalLinks                   matlab.ui.control.NumericEditField
        LinkType                     matlab.ui.container.ButtonGroup
        Revolute                     matlab.ui.control.RadioButton
        Prismatic                    matlab.ui.control.RadioButton
        aInput                       matlab.ui.control.NumericEditField
        LengthoflinkLabel            matlab.ui.control.Label
        AnglebetweentheZaxisoftheconsecutivelinksLabel  matlab.ui.control.Label
        alphaInput                   matlab.ui.control.NumericEditField
        AnglebetweentheXaxisoftheconsecutivelinksLabel  matlab.ui.control.Label
        thetaInput                   matlab.ui.control.NumericEditField
        dInput                       matlab.ui.control.NumericEditField
        DistancebetweenjointsinZaxisofpreviousjointLabel  matlab.ui.control.Label
        PrismaticJointLimitsLabel    matlab.ui.control.Label
        Plimmin                      matlab.ui.control.NumericEditField
        Plimmax                      matlab.ui.control.NumericEditField
        MinLabel                     matlab.ui.control.Label
        MaxLabel                     matlab.ui.control.Label
        DHUpdate                     matlab.ui.control.Button
    end

    
    properties (Access = private)
         theta = [];
        a      = [];
        d      = [];
        alpha  = [];
        INVS   = [];
        q      = [];
        min    = [];
        max    = [];
        type   = [];
        jvalue = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run startup_rvc.m
        end

        % Button pushed function: DHUpdate
        function DHUpdateButtonPushed(app, event)
            n = app.TotalLinks.Value;
            i = app.LinkNo.Value;
            app.theta(i) = app.thetaInput.Value;
            app.d(i) = app.dInput.Value;
            app.a(i) = app.aInput.Value;
            app.alpha(i) = app.alphaInput.Value;
            app.min(i) = app.Plimmin.Value; 
            app.max(i) = app.Plimmax.Value;
            app.type(i) = app.LinkType.SelectedObject;
            temp = round(app.type);
            if i==n
                for i = 1:n
                    DH(i,:) = [(pi/180)*app.theta(i), app.d(i), app.a(i), (pi/180)*app.alpha(i)];
                end
                app.DHTable.Data = DH;
            end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            i = app.JointNo.Value;
            n =  app.TotalLinks.Value;
            app.jvalue(i) = app.JointVariable.Value;
            DH = app.DHTable.Data;
            low = app.min;
            high = app.max;
            temp = round(app.type);
            if i == n
                 for k = 1:n
                     if temp(:,k) == 3
                          L{k} = Link('d',DH(k,2), 'a', DH(k,3), 'alpha', DH(k,4));
                     else 
                          L{k} = Link('theta',DH(k,1), 'a', DH(k,3), 'alpha', DH(k,4)) 
                          L{k}.qlim = [low(k) high(k)];
                     end
                 end
                 for b = 1:n
                     X(b,:) = L{b};
                 end
                 R = SerialLink(X)
                 trans = R.fkine(app.jvalue)
                 app.TransformationTable.Data = trans;
                 W = [-15 15 -15 15 -15 15];
                 R.plot(app.jvalue, 'workspace', W)
                 teach(R)
            end
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            clear all
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DHTable = uitable(app.UIFigure);
            app.DHTable.ColumnName = {'Theta'; 'd'; 'a'; 'alpha'};
            app.DHTable.RowName = {''};
            app.INVERSEKINEMATICS = uilabel(app.UIFigure);
            app.INVERSEKINEMATICS.Text = 'FORWARD KINEMATICS';
            app.AllValuesforAnglesmustbeenteredinDEGREEUnitONLYLabel = uilabel(app.UIFigure);
            app.AllValuesforAnglesmustbeenteredinDEGREEUnitONLYLabel.Text = 'All Values for Angles must be entered in DEGREE Unit ONLY';
            app.ValueofJointVariablesPanel = uipanel(app.UIFigure);
            app.ValueofJointVariablesPanel.Title = 'Value of Joint Variables';
            app.JointNoEditFieldLabel = uilabel(app.ValueofJointVariablesPanel);
            app.JointNoEditFieldLabel.Text = 'Joint No.';
            app.JointNo = uieditfield(app.ValueofJointVariablesPanel, 'numeric');
            app.ValueEditFieldLabel = uilabel(app.ValueofJointVariablesPanel);
            app.ValueEditFieldLabel.Text = 'Value';
            app.JointVariable = uieditfield(app.ValueofJointVariablesPanel, 'numeric');
            app.TextArea = uitextarea(app.ValueofJointVariablesPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Enter Value for Revolute in Degrees and Prismatic in m'};
            app.RUNButton = uibutton(app.ValueofJointVariablesPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.ValueofTbePanel = uipanel(app.UIFigure);
            app.ValueofTbePanel.Title = 'Value of Tbe';
            app.TransformationTable = uitable(app.ValueofTbePanel);
            app.TransformationTable.ColumnName = {''};
            app.TransformationTable.RowName = {};
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.LinkDefinations = uipanel(app.UIFigure);
            app.LinkDefinations.Title = 'Enter Link Definations one by one';
            app.LinkNo = uieditfield(app.LinkDefinations, 'numeric');
            app.LinkNoLabel = uilabel(app.LinkDefinations);
            app.LinkNoLabel.Text = 'Link No.';
            app.NumberofLinksEditFieldLabel = uilabel(app.LinkDefinations);
            app.NumberofLinksEditFieldLabel.Text = 'Number of Links';
            app.TotalLinks = uieditfield(app.LinkDefinations, 'numeric');
            app.LinkType = uibuttongroup(app.LinkDefinations);
            app.LinkType.Title = 'Link Type';
            app.Revolute = uiradiobutton(app.LinkType);
            app.Revolute.Text = 'Revolute';
            app.Revolute.Value = true;
            app.Prismatic = uiradiobutton(app.LinkType);
            app.Prismatic.Text = 'Prismatic';
            app.aInput = uieditfield(app.LinkDefinations, 'numeric');
            app.LengthoflinkLabel = uilabel(app.LinkDefinations);
            app.LengthoflinkLabel.Text = 'Length of link';
            app.AnglebetweentheZaxisoftheconsecutivelinksLabel = uilabel(app.LinkDefinations);
            app.AnglebetweentheZaxisoftheconsecutivelinksLabel.Text = 'Angle between the Z axis of the consecutive links';
            app.alphaInput = uieditfield(app.LinkDefinations, 'numeric');
            app.AnglebetweentheXaxisoftheconsecutivelinksLabel = uilabel(app.LinkDefinations);
            app.AnglebetweentheXaxisoftheconsecutivelinksLabel.Text = 'Angle between the X axis of the consecutive links';
            app.thetaInput = uieditfield(app.LinkDefinations, 'numeric');
            app.dInput = uieditfield(app.LinkDefinations, 'numeric');
            app.DistancebetweenjointsinZaxisofpreviousjointLabel = uilabel(app.LinkDefinations);
            app.DistancebetweenjointsinZaxisofpreviousjointLabel.Text = 'Distance between joints in Z axis of previous joint';
            app.PrismaticJointLimitsLabel = uilabel(app.LinkDefinations);
            app.PrismaticJointLimitsLabel.Text = 'Prismatic Joint Limits';
            app.Plimmin = uieditfield(app.LinkDefinations, 'numeric');
            app.Plimmax = uieditfield(app.LinkDefinations, 'numeric');
            app.MinLabel = uilabel(app.LinkDefinations);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.LinkDefinations);
            app.MaxLabel.Text = 'Max';
            app.DHUpdate = uibutton(app.LinkDefinations, 'push');
            app.DHUpdate.ButtonPushedFcn = createCallbackFcn(app, @DHUpdateButtonPushed, true);
            app.DHUpdate.Text = 'UPDATE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Forward_Kinematics_noDH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end