classdef Inverse_Kinematics_Diffrential < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DHTable                      matlab.ui.control.Table
        LinkDefinations              matlab.ui.container.Panel
        LinkNo                       matlab.ui.control.NumericEditField
        thetaInput                   matlab.ui.control.NumericEditField
        dInput                       matlab.ui.control.NumericEditField
        aInput                       matlab.ui.control.NumericEditField
        alphaInput                   matlab.ui.control.NumericEditField
        DHUpdate                     matlab.ui.control.Button
        LinkNoLabel                  matlab.ui.control.Label
        ThetaLabel                   matlab.ui.control.Label
        dLabel                       matlab.ui.control.Label
        aLabel                       matlab.ui.control.Label
        alphaLabel                   matlab.ui.control.Label
        NumberofLinksEditFieldLabel  matlab.ui.control.Label
        TotalLinks                   matlab.ui.control.NumericEditField
        Plimmin                      matlab.ui.control.NumericEditField
        Plimmax                      matlab.ui.control.NumericEditField
        PrismaticJointLimitsLabel    matlab.ui.control.Label
        MinLabel                     matlab.ui.control.Label
        MaxLabel                     matlab.ui.control.Label
        LinkType                     matlab.ui.container.ButtonGroup
        Revolute                     matlab.ui.control.RadioButton
        Prismatic                    matlab.ui.control.RadioButton
        INVERSEKINEMATICS            matlab.ui.control.Label
        AllValuesforAnglesmustbeenteredinDEGREEUnitONLYLabel  matlab.ui.control.Label
        EndPoseValues                matlab.ui.container.Panel
        WxLabel                      matlab.ui.control.Label
        WX                           matlab.ui.control.NumericEditField
        WyLabel                      matlab.ui.control.Label
        WY                           matlab.ui.control.NumericEditField
        WzLabel                      matlab.ui.control.Label
        WZ                           matlab.ui.control.NumericEditField
        VxLabel                      matlab.ui.control.Label
        PX                           matlab.ui.control.NumericEditField
        VyLabel                      matlab.ui.control.Label
        PY                           matlab.ui.control.NumericEditField
        ValueofLinearinmsandangularinRadsLabel  matlab.ui.control.Label
        VzLabel                      matlab.ui.control.Label
        PZ                           matlab.ui.control.NumericEditField
        RUNINVERSEButton             matlab.ui.control.Button
        Qdot                         matlab.ui.control.Table
    end

    
    properties (Access = private)
        theta = [];
        a   =   [];
        d   =   [];
        alpha = [];
        INVS =  [];
        min  =  [];
        max  =  [];
        type =  [];
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run startup_rvc.m
        end

        % Button pushed function: DHUpdate
        function DHUpdateButtonPushed(app, event)
              n = app.TotalLinks.Value;
            i = app.LinkNo.Value;
            app.theta(i) = app.thetaInput.Value;
            app.d(i) = app.dInput.Value;
            app.a(i) = app.aInput.Value;
            app.alpha(i) = app.alphaInput.Value;
            app.min(i) = app.Plimmin.Value; 
            app.max(i) = app.Plimmax.Value;
            app.type(i) = app.LinkType.SelectedObject;
            temp = round(app.type);
            if i==n
                for i = 1:n
                    DH(i,:) = [(pi/180)*app.theta(i), app.d(i), app.a(i), (pi/180)*app.alpha(i)];
                end
                app.DHTable.Data = DH;
            end
               
        end

        % Button pushed function: RUNINVERSEButton
        function RUNINVERSEButtonPushed(app, event)
             n =  app.TotalLinks.Value;
            DH = app.DHTable.Data;
            low = app.min;
            high = app.max;
            temp = round(app.type)
            Vx = app.PX.Value;
            Vy = app.PY.Value;
            Vz = app.PZ.Value;
            Wx   = app.WX.Value;
            Wy = app.WY.Value;
            Wz   = app.WZ.Value;
%            for k = 1:n
%                 if temp(:,k) == 3
%                      L{k} = Link('d',DH(k,2), 'a', DH(k,3), 'alpha', DH(k,4));
%                 else 
%                     L{k} = Link('theta',DH(k,1), 'a', DH(k,3), 'alpha', DH(k,4)) 
%                      L{k}.qlim = [low(k) high(k)];
%                 end
%             end
%             for b = 1:n
%                 X(b,:) = L{b};
%             end
%        
%             R = SerialLink(X)
            
           [q_dot] = Inverse_Diffrential(n, temp, DH, low, high, Vx, Vy, Vz, Wx, Wy, Wz, q0)
           app.Qdot.Data = q_dot;
                      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DHTable = uitable(app.UIFigure);
            app.DHTable.ColumnName = {'Theta'; 'd'; 'a'; 'alpha'};
            app.DHTable.RowName = {''};
            app.LinkDefinations = uipanel(app.UIFigure);
            app.LinkDefinations.Title = 'Enter Link Definations one by one';
            app.LinkNo = uieditfield(app.LinkDefinations, 'numeric');
            app.thetaInput = uieditfield(app.LinkDefinations, 'numeric');
            app.dInput = uieditfield(app.LinkDefinations, 'numeric');
            app.aInput = uieditfield(app.LinkDefinations, 'numeric');
            app.alphaInput = uieditfield(app.LinkDefinations, 'numeric');
            app.DHUpdate = uibutton(app.LinkDefinations, 'push');
            app.DHUpdate.ButtonPushedFcn = createCallbackFcn(app, @DHUpdateButtonPushed, true);
            app.DHUpdate.Text = 'UPDATE';
            app.LinkNoLabel = uilabel(app.LinkDefinations);
            app.LinkNoLabel.Text = 'Link No.';
            app.ThetaLabel = uilabel(app.LinkDefinations);
            app.ThetaLabel.Text = 'Theta';
            app.dLabel = uilabel(app.LinkDefinations);
            app.dLabel.Text = 'd';
            app.aLabel = uilabel(app.LinkDefinations);
            app.aLabel.Text = 'a';
            app.alphaLabel = uilabel(app.LinkDefinations);
            app.alphaLabel.Text = 'alpha';
            app.NumberofLinksEditFieldLabel = uilabel(app.LinkDefinations);
            app.NumberofLinksEditFieldLabel.Text = 'Number of Links';
            app.TotalLinks = uieditfield(app.LinkDefinations, 'numeric');
            app.Plimmin = uieditfield(app.LinkDefinations, 'numeric');
            app.Plimmax = uieditfield(app.LinkDefinations, 'numeric');
            app.PrismaticJointLimitsLabel = uilabel(app.LinkDefinations);
            app.PrismaticJointLimitsLabel.Text = 'Prismatic Joint Limits';
            app.MinLabel = uilabel(app.LinkDefinations);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.LinkDefinations);
            app.MaxLabel.Text = 'Max';
            app.LinkType = uibuttongroup(app.LinkDefinations);
            app.LinkType.Title = 'Link Typ';
            app.Revolute = uiradiobutton(app.LinkType);
            app.Revolute.Text = 'Revolute';
            app.Revolute.Value = true;
            app.Prismatic = uiradiobutton(app.LinkType);
            app.Prismatic.Text = 'Prismatic';
            app.INVERSEKINEMATICS = uilabel(app.UIFigure);
            app.INVERSEKINEMATICS.Text = 'INVERSE DIFFRENTIAL KINEMATICS';
            app.AllValuesforAnglesmustbeenteredinDEGREEUnitONLYLabel = uilabel(app.UIFigure);
            app.AllValuesforAnglesmustbeenteredinDEGREEUnitONLYLabel.Text = 'All Values for Angles must be entered in DEGREE Unit ONLY';
            app.EndPoseValues = uipanel(app.UIFigure);
            app.EndPoseValues.Title = 'Values of Robot''s End Pose';
            app.WxLabel = uilabel(app.EndPoseValues);
            app.WxLabel.Text = 'Wx';
            app.WX = uieditfield(app.EndPoseValues, 'numeric');
            app.WyLabel = uilabel(app.EndPoseValues);
            app.WyLabel.Text = 'Wy';
            app.WY = uieditfield(app.EndPoseValues, 'numeric');
            app.WzLabel = uilabel(app.EndPoseValues);
            app.WzLabel.Text = 'Wz';
            app.WZ = uieditfield(app.EndPoseValues, 'numeric');
            app.VxLabel = uilabel(app.EndPoseValues);
            app.VxLabel.Text = 'Vx';
            app.PX = uieditfield(app.EndPoseValues, 'numeric');
            app.VyLabel = uilabel(app.EndPoseValues);
            app.VyLabel.Text = 'Vy';
            app.PY = uieditfield(app.EndPoseValues, 'numeric');
            app.VzLabel = uilabel(app.EndPoseValues);
            app.VzLabel.Text = 'Vz';
            app.PZ = uieditfield(app.EndPoseValues, 'numeric');
            app.RUNINVERSEButton = uibutton(app.EndPoseValues, 'push');
            app.RUNINVERSEButton.ButtonPushedFcn = createCallbackFcn(app, @RUNINVERSEButtonPushed, true);
            app.RUNINVERSEButton.Text = 'RUN INVERSE';
            app.ValueofLinearinmsandangularinRadsLabel = uilabel(app.EndPoseValues);
            app.ValueofLinearinmsandangularinRadsLabel.Text = 'Value of Linear in m/s and angular in Rad/s';
            app.Qdot = uitable(app.UIFigure);
            app.Qdot.ColumnName = {''};
            app.Qdot.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Inverse_Kinematics_Diffrential

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end