classdef Rotation_matrix < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        NumberofRotationalTransforamtionEditFieldLabel  matlab.ui.control.Label
        NumberofRotationalTransforamtion  matlab.ui.control.NumericEditField
        CurrentFrameButton  matlab.ui.control.Button
        FixedFrameButton    matlab.ui.control.Button
        RMtable             matlab.ui.control.Table
        AATable             matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CurrentFrameButton, RMtable
        function CurrentFrameButtonPushed(app, event)

%Translational matrix Specifing axis and angle of rotation%
%Number of Rotational transformation%
% answer = questdlg('Which Frame do you want?', ...
% 	'Frame ', ...
% 	'Fixed Frame','Current Frame','Current Frame');
NRT= app.NumberofRotationalTransforamtion.Value;
%NRT=str2double(inputdlg('Number of Rotational Transformation ')); 
syms x y z 
theta=ones(NRT,1);
r=eye(3);
P0=zeros(1,3);
R=eye(3);
for i=1:NRT   
    prompt1=['Angle of rotation theta in degree ',num2str(i)];
    prompt2=[num2str(i), ' Axis of Rotation (x OR y OR z) '];
     digtitle=[num2str(i),' Rotation'];
homTran_inp=inputdlg({ prompt1, prompt2},digtitle);
theta(i)=str2double(homTran_inp(1));
Axis(i)=homTran_inp(2);
k(i)=homTran_inp(2);
if k(i)==x
    k1=1;
    k2=0;
    k3=0;
end
if k(i)==y
    k1=0;        
    k2=1;
    k3=0;
end
if k(i)==z
    k1=0;
    k2=0;
    k3=1;
end
% k1=k(1,1);
% k2=k(2,1);
% k3=k(3,1);
st=sind(theta(i));
ct=cosd(theta(i));
vt=(1-ct);

r1=[k1^2*vt+ct        k1*k2*vt-k3*st    k1*k3*vt+k2*st ;
   k1*k2*vt+k3*st    k2^2*vt+ct        k2*k3*vt-k1*st ;
   k1*k3*vt-k2*st    k2*k3*vt+k1*st    k3^2*vt+ct ];
eval(sprintf('E%d = r1;',i));
r=r1;
eval(sprintf('E%d',i))
% if strcmp(answer,'Current Frame')== 1
R=R*r1;    
% else
% R=r1*R;
% end

Q1=rotm2quat(R);
 eval(sprintf('QD%d = Q1;',i));
  q=Q1;
 eval(sprintf('QD%d',i))
    
end
figure("Name",'Rotational Transforamtion')
grid on
plotTransforms(P0,[1 0 0 0]) 
hold on
plotTransforms(P0,Q1) 
xlabel('X-axis Red');
ylabel('Y-axis Green');
zlabel('Z-axis Blue');
disp(['Rotational Matrix from 0 to ',num2str(i)] )
disp(R)
Input_Axis_Angle=cell2table([Axis' num2cell(theta)]);
app.AATable.Data=Input_Axis_Angle;
app.RMtable.Data=R;
        end

        % Button pushed function: FixedFrameButton
        function FixedFrameButtonPushed(app, event)
            %Translational matrix Specifing axis and angle of rotation%
%Number of Rotational transformation%
% answer = questdlg('Which Frame do you want?', ...
% 	'Frame ', ...
% 	'Fixed Frame','Current Frame','Current Frame');
NRT= app.NumberofRotationalTransforamtion.Value;
%NRT=str2double(inputdlg('Number of Rotational Transformation ')); 
syms x y z 
theta=ones(NRT,1);
r=eye(3);
P0=zeros(1,3);
R=eye(3);
for i=1:NRT   
    prompt1=['Angle of rotation theta in degree ',num2str(i)];
    prompt2=[num2str(i), ' Axis of Rotation (x OR y OR z) '];
     digtitle=[num2str(i),' Rotation'];
homTran_inp=inputdlg({ prompt1, prompt2},digtitle);
theta(i)=str2double(homTran_inp(1));
k(i)=homTran_inp(2);
if k(i)==x
    k1=1;
    k2=0;
    k3=0;
end
if k(i)==y
    k1=0;        
    k2=1;
    k3=0;
end
if k(i)==z
    k1=0;
    k2=0;
    k3=1;
end
% k1=k(1,1);
% k2=k(2,1);
% k3=k(3,1);
st=sind(theta(i));
ct=cosd(theta(i));
vt=(1-ct);

r1=[k1^2*vt+ct        k1*k2*vt-k3*st    k1*k3*vt+k2*st ;
   k1*k2*vt+k3*st    k2^2*vt+ct        k2*k3*vt-k1*st ;
   k1*k3*vt-k2*st    k2*k3*vt+k1*st    k3^2*vt+ct ];
eval(sprintf('E%d = r1;',i));
r=r1;
eval(sprintf('E%d',i))
% if strcmp(answer,'Current Frame')== 1
%R=R*r1;    
% else
R=r1*R;
% end

Q1=rotm2quat(R);
 eval(sprintf('QD%d = Q1;',i));
  q=Q1;
 eval(sprintf('QD%d',i))
    
end
figure("Name",'Rotational Transforamtion')
grid on
plotTransforms(P0,[1 0 0 0]) 
hold on
plotTransforms(P0,Q1) 
xlabel('X-axis Red');
ylabel('Y-axis Green');
zlabel('Z-axis Blue');
disp(['Rotational Matrix from 0 to ',num2str(i)] )
disp(R)
Input_Axis_Angle=cell2table([Axis' num2cell(theta)]);
app.AATable.Data=Input_Axis_Angle;
app.RMtable.Data=R;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NumberofRotationalTransforamtionEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofRotationalTransforamtionEditFieldLabel.Text = 'Number of Rotational Transforamtion';
            app.NumberofRotationalTransforamtion = uieditfield(app.UIFigure, 'numeric');
            app.CurrentFrameButton = uibutton(app.UIFigure, 'push');
            app.CurrentFrameButton.ButtonPushedFcn = createCallbackFcn(app, @CurrentFrameButtonPushed, true);
            app.CurrentFrameButton.Text = 'Current Frame';
            app.FixedFrameButton = uibutton(app.UIFigure, 'push');
            app.FixedFrameButton.ButtonPushedFcn = createCallbackFcn(app, @FixedFrameButtonPushed, true);
            app.FixedFrameButton.Text = 'Fixed Frame';
            app.RMtable = uitable(app.UIFigure);
            app.RMtable.ColumnName = {'Rotation Matrix'};
            app.RMtable.RowName = {};
            app.RMtable.DisplayDataChangedFcn = createCallbackFcn(app, @CurrentFrameButtonPushed, true);
            app.AATable = uitable(app.UIFigure);
            app.AATable.ColumnName = {'Axis'; 'Angle'};
            app.AATable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rotation_matrix

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end