classdef workspace1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LinkDefinations              matlab.ui.container.Panel
        LinkNo                       matlab.ui.control.NumericEditField
        thetaInput                   matlab.ui.control.NumericEditField
        dInput                       matlab.ui.control.NumericEditField
        aInput                       matlab.ui.control.NumericEditField
        alphaInput                   matlab.ui.control.NumericEditField
        DHUpdate                     matlab.ui.control.Button
        LinkNoLabel                  matlab.ui.control.Label
        ThetaLabel                   matlab.ui.control.Label
        dLabel                       matlab.ui.control.Label
        aLabel                       matlab.ui.control.Label
        alphaLabel                   matlab.ui.control.Label
        NumberofLinksEditFieldLabel  matlab.ui.control.Label
        TotalLinks                   matlab.ui.control.NumericEditField
        Plimmin                      matlab.ui.control.NumericEditField
        Plimmax                      matlab.ui.control.NumericEditField
        PrismaticJointLimitsLabel    matlab.ui.control.Label
        MinLabel                     matlab.ui.control.Label
        MaxLabel                     matlab.ui.control.Label
        Linktype                     matlab.ui.control.EditField
        TextArea                     matlab.ui.control.TextArea
        DHTable                      matlab.ui.control.Table
        INVERSEKINEMATICS            matlab.ui.control.Label
        AllAnglesareinDEGREELabel    matlab.ui.control.Label
        RESETButton                  matlab.ui.control.Button
        RUNButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        theta = [];
        a      = [];
        d      = [];
        alpha  = [];
        INVS   = [];
        q      = [];
        min    = [];
        max    = [];
        type   = [];
        jvalue = [];
        dh     = [];
        jpconf  = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run startup_rvc.m
        end

        % Button pushed function: DHUpdate
        function DHUpdateButtonPushed(app, event)
            n = app.TotalLinks.Value;
            i = app.LinkNo.Value;
            app.theta(i) = app.thetaInput.Value;
            app.d(i) = app.dInput.Value;
            app.a(i) = app.aInput.Value;
            app.alpha(i) = app.alphaInput.Value;
            app.min(i) = app.Plimmin.Value; 
            app.max(i) = app.Plimmax.Value;
            app.type(i) = app.Linktype.Value;
            temp = (app.type);
            if i==n
                for i = 1:n
                    DH(i,:) = [(pi/180)*app.theta(i), app.d(i), app.a(i), (pi/180)*app.alpha(i)];
                end
                app.DHTable.Data = DH;
            end
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
 x=  app.TotalLinks.Value;

q1=app.theta;
a=app.a;
d=app.d;
alpha=app.alpha;
DH = app.DHTable.Data;
low = app.min;
high = app.max;
temp = round(app.type)

for k = 1:x
                if temp(:,x) == 114
                     L{k} = Link('d',DH(k,2), 'a', DH(k,3), 'alpha', DH(k,4));
                     %L{k}.qlim = [low(k) high(k)];
                     q(k,1:2)=[low(k) high(k)]
                elseif temp(:,x) == 112
                    L{k} = Link('theta',DH(k,1), 'a', DH(k,3), 'alpha', DH(k,4)) 
                    %L{k}.qlim = [low(k) high(k)];
                    q(k,1:2)=[low(k) high(k)]
                end
end
            for b = 1:x
                X(b,:) = L{b};
            end
       
n = 1:x;
m =[X(n)];
R = SerialLink(m);
            temp = round(app.type);
 if x>=6
        t=zeros(90,x);
    for i=1:x
        if temp(:,x)== 114
            t(:,i)=linspace( rad2deg(q(i,1)),rad2deg(q(i,2)),90);
        elseif temp(:,x)== 112
            t(:,i)=linspace(q(i,1),q(i,2),90);
        end
        %T{i}=ndgrid(t{i});
    end

    for j=1:2
        for i = 1:x    
            Tr{i,j} = [cos(q(i,j)) -sin(q(i,j))*(cos(alpha(i))) sin(q(i,j))*(sin(alpha(i))) a(i)*cos(q(i,j));
                    sin(q(i,j)) cos(q(i,j))*(cos(alpha(i))) -cos(q(i,j))*(sin(alpha(i))) a(i)*sin(q(i,j));
                    0 (sin(alpha(i))) (cos(alpha(i))) d(i);0 0 0 1];
     end
    end
     T0i1{1}=Tr{1,1};
     for i=1:x-1
         T0i1{i+1}=T0i1{i}*Tr{i+1,1};
     end
    T0i2{1}=Tr{1,2};
     for i=1:x-1
         T0i2{i+1}=T0i2{i}*Tr{i+1,2};
     end

    T1=[eye(3) T0i1{2}(1:3,4);0 0 0 1];
    t2=[eye(3) T0i2{2}(1:3,4);0 0 0 1];
    w=zeros(10000,x);

    % m=zeros(1,6)
    % m1=0
    % while m1<x
    %     r=randperm(6,1)
    %     if m(r)~=1
    %         m(r)=1;
    %         m1=m1+1;
    %     end
    % end
     m=[1 0 0 1 1 1];
    %q12=R.ikunc(T1,T0i2{2}(1:4,4)')
    
    if x>=6
        w=R.jtraj(T1, t2, 10000);
    else
        w=R.jtraj(T1,t2,10000,R.ikine(T1,T0i2{2}(1:4,4)',m','pinv'));
    end

    q_f1=R.fkine(w);

    x_traj = zeros(1,10000);
    y_traj = zeros(1,10000);
    z_traj = zeros(1,10000);

    for i=1:10000
    x_traj(1,i) = q_f1(1,4,i);
    y_traj(1,i) = q_f1(2,4,i);
    z_traj(1,i) = q_f1(3,4,i);
    end

    figure(1)
    workspx=reshape(x_traj(1,:),[2000,5]);
    workspy=reshape(y_traj(1,:),[2000,5]);
    workspz=reshape(z_traj(1,:),[2000,5]);
    surf(workspx(:,:),workspy(:,:),workspz(:,:));

    figure(2)
    hold on
    for i=1:10000
    %R.plot([w(i,:)],'fps',200);
    [x,y,z] = sphere(16);
    plot3(x_traj(1,i),y_traj(1,i),z_traj(1,i),'.');
    hold on;
    end
    hold on;

    vstup=0;
    while (vstup ~= 1)
    R.plot(w,'delay',1e-20)
    end
    hold off;
end
 
%%%% Workspace for underactuated robots %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

if x<6
    t=zeros(1000,x);
    for i=1:x
        if temp(:,x)==114
            t(:,i)=linspace( q(i,1),q(i,2),1000)
        elseif temp(:,x)==112
            t(:,i)=linspace(q(i,1),q(i,2),1000)
        end
    end
    
    figure(1);
    N=30;
    J = R.fkine(t);
    for i= 0:N
        for j= 1:N+1
            TR= J(:,4,i*(30)+j);
            workspace(i+1,j,:) = TR;
        end
    end
    surf(workspace(:,:,1), workspace(:,:,2), workspace(:,:,3))
    
    space=[0:.01:10];
    [Q QD QDD]=jtraj(q(:,1),q(:,2),space);
    % J=fkine(R, Q);    
%     figure(2);
%     for x=1:1:1000
%         plot3(J(1,4,x),J(2,4,x),J(3,4,x),'b.')
%         hold on;
%        % R.plot([t(x,:)],'workspace',[-1 1 -1 1 -1 1]);
%     end
%     hold on; 
%     vstup=0;
%     while (vstup ~= 1)
%     R.plot(Q,'delay',1e-20)
%     end
%     hold off;
end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            clear all
        end

        % Callback function
        function UpdateButtonPushed(app, event)
             app.jpconf(app.JointNoEditField.Value)= app.JointVariableValueEditField.Value;
            a=app.jpconf;
        end

        % Callback function
        function RUNButton_2Pushed(app, event)
            app.SingularityTextArea.Value = string(R.maniplty(app.jpconf));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LinkDefinations = uipanel(app.UIFigure);
            app.LinkDefinations.Title = 'Enter Link Definations one by one';
            app.LinkNo = uieditfield(app.LinkDefinations, 'numeric');
            app.thetaInput = uieditfield(app.LinkDefinations, 'numeric');
            app.dInput = uieditfield(app.LinkDefinations, 'numeric');
            app.aInput = uieditfield(app.LinkDefinations, 'numeric');
            app.alphaInput = uieditfield(app.LinkDefinations, 'numeric');
            app.DHUpdate = uibutton(app.LinkDefinations, 'push');
            app.DHUpdate.ButtonPushedFcn = createCallbackFcn(app, @DHUpdateButtonPushed, true);
            app.DHUpdate.Text = 'UPDATE';
            app.LinkNoLabel = uilabel(app.LinkDefinations);
            app.LinkNoLabel.Text = 'Link No.';
            app.ThetaLabel = uilabel(app.LinkDefinations);
            app.ThetaLabel.Text = 'Theta';
            app.dLabel = uilabel(app.LinkDefinations);
            app.dLabel.Text = 'd';
            app.aLabel = uilabel(app.LinkDefinations);
            app.aLabel.Text = 'a';
            app.alphaLabel = uilabel(app.LinkDefinations);
            app.alphaLabel.Text = 'alpha';
            app.NumberofLinksEditFieldLabel = uilabel(app.LinkDefinations);
            app.NumberofLinksEditFieldLabel.Text = 'Number of Links';
            app.TotalLinks = uieditfield(app.LinkDefinations, 'numeric');
            app.Plimmin = uieditfield(app.LinkDefinations, 'numeric');
            app.Plimmax = uieditfield(app.LinkDefinations, 'numeric');
            app.PrismaticJointLimitsLabel = uilabel(app.LinkDefinations);
            app.PrismaticJointLimitsLabel.Text = 'Prismatic Joint Limits';
            app.MinLabel = uilabel(app.LinkDefinations);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.LinkDefinations);
            app.MaxLabel.Text = 'Max';
            app.Linktype = uieditfield(app.LinkDefinations, 'text');
            app.TextArea = uitextarea(app.LinkDefinations);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Enter r for revolute and p for prismatic'};
            app.DHTable = uitable(app.UIFigure);
            app.DHTable.ColumnName = {'Theta'; 'd'; 'a'; 'alpha'};
            app.DHTable.RowName = {''};
            app.INVERSEKINEMATICS = uilabel(app.UIFigure);
            app.INVERSEKINEMATICS.Text = 'Differential Kinematics (Numerical)';
            app.AllAnglesareinDEGREELabel = uilabel(app.UIFigure);
            app.AllAnglesareinDEGREELabel.Text = 'All  Angles are in DEGREE ';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = workspace1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end