classdef Differnetial_Kinematics_Numercial_noDH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DifferentialKinematicsnoDHUIFigure  matlab.ui.Figure
        DHTable                        matlab.ui.control.Table
        INVERSEKINEMATICS              matlab.ui.control.Label
        AllAnglesareinDEGREELabel      matlab.ui.control.Label
        JacobianPanel                  matlab.ui.container.Panel
        TransformationTable            matlab.ui.control.Table
        RESETButton                    matlab.ui.control.Button
        NumberoflineralydependnetjointsTextAreaLabel  matlab.ui.control.Label
        Numberoflineralydependnetjoints  matlab.ui.control.TextArea
        ColumnsthatarelinearlydependentTextAreaLabel  matlab.ui.control.Label
        Columnsthatarelinearlydependent  matlab.ui.control.TextArea
        SingularityforaGivenPosePanel  matlab.ui.container.Panel
        JointNoEditFieldLabel          matlab.ui.control.Label
        JointNoEditField               matlab.ui.control.NumericEditField
        JointVariableValueEditFieldLabel  matlab.ui.control.Label
        JointVariableValueEditField    matlab.ui.control.NumericEditField
        UpdateButton                   matlab.ui.control.Button
        SingularityTextAreaLabel       matlab.ui.control.Label
        SingularityTextArea            matlab.ui.control.TextArea
        RUNButton_2                    matlab.ui.control.Button
        RUNButton                      matlab.ui.control.Button
        LinkDefinations                matlab.ui.container.Panel
        LinkNo                         matlab.ui.control.NumericEditField
        LinkNoLabel                    matlab.ui.control.Label
        NumberofLinksEditFieldLabel    matlab.ui.control.Label
        TotalLinks                     matlab.ui.control.NumericEditField
        aInput                         matlab.ui.control.NumericEditField
        LengthoflinkLabel              matlab.ui.control.Label
        AnglebetweentheZaxisoftheconsecutivelinksLabel  matlab.ui.control.Label
        alphaInput                     matlab.ui.control.NumericEditField
        AnglebetweentheXaxisoftheconsecutivelinksLabel  matlab.ui.control.Label
        thetaInput                     matlab.ui.control.NumericEditField
        dInput                         matlab.ui.control.NumericEditField
        DistancebetweenjointsinZaxisofpreviousjointLabel  matlab.ui.control.Label
        PrismaticJointLimitsLabel      matlab.ui.control.Label
        Plimmin                        matlab.ui.control.NumericEditField
        Plimmax                        matlab.ui.control.NumericEditField
        MinLabel                       matlab.ui.control.Label
        MaxLabel                       matlab.ui.control.Label
        DHUpdate                       matlab.ui.control.Button
        Linktype                       matlab.ui.control.EditField
        TextArea                       matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        theta = [];
        a      = [];
        d      = [];
        alpha  = [];
        INVS   = [];
        q      = [];
        min    = [];
        max    = [];
        type   = [];
        jvalue = [];
        dh     = [];
        jpconf  = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run startup_rvc.m
        end

        % Button pushed function: DHUpdate
        function DHUpdateButtonPushed(app, event)
            n = app.TotalLinks.Value;
            i = app.LinkNo.Value;
            app.theta(i) = app.thetaInput.Value;
            app.d(i) = app.dInput.Value;
            app.a(i) = app.aInput.Value;
            app.alpha(i) = app.alphaInput.Value;
            app.min(i) = app.Plimmin.Value; 
            app.max(i) = app.Plimmax.Value;
            app.type(i) = app.Linktype.Value;
            temp = (app.type);
            if i==n
                for i = 1:n
                    DH(i,:) = [(pi/180)*app.theta(i), app.d(i), app.a(i), (pi/180)*app.alpha(i)];
                end
                app.DHTable.Data = DH;
            end
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
 x=  app.TotalLinks.Value;

q=app.theta;
a=app.a;
d=app.d;
alpha=app.alpha;
 for i = 1:x    
     
         T{i} = [cosd(q(i)) -sind(q(i))*cosd(alpha(i)) sind(q(i))*sind(alpha(i)) a(i)*cosd(q(i));
            sind(q(i)) cosd(q(i))*cosd(alpha(i)) -cosd(q(i))*sind(alpha(i)) a(i)*sind(q(i));
            0 sind(alpha(i)) cosd(alpha(i)) d(i);        0 0 0 1];
 end

 T0i{1}=T{1};
 for i=1:x-1
     T0i{i+1}=T0i{i}*T{i+1};
 end
 
 p0=[0 0 0]';
 z0=[0 0 1]'
 for i=1:x
     p(1:3,i)=T0i{i}(1:3,4);
     z(1:3,i)=T0i{i}(1:3,3);
 end

 temp = round(app.type);
                              
 for i=1:x
     if temp(:,x) == 4
         if i==1
            Jp(1:3,i)= z0;
            Jo(1:3,i)= [0 0 0]';
         else
            Jp(1:3,i)= z(:,i-1);
            Jo(1:3,i)= [0 0 0]';
         end
         
     else
         if i==1
            Jp(1:3,i)= cross(z0,p(:,x)-p0);
            Jo(1:3,i)= z0;
         else
            Jp(1:3,i)= cross(z(:,i-1),p(:,x)-p(:,i-1));
            Jo(1:3,i)= z(:,i-1);
         end
     end
 end
 J=[Jp;Jo]
 app.TransformationTable.Data = J;
 for i=1:x
    if temp(:,x) == 114
        L{i}=Link('d',d(i),'a',a(i),'alpha',alpha(i));
    else
        L{i}=Link('theta',q(i),'a',a(i),'alpha',alpha(i));
    end
end

for b = 1:x
    X(b) = L{b};
end
n = 1:x;
m =[X(n)];
R = SerialLink(m);
 

% jsingu(J);
R1=1;
i=1;
depcol=[];
for I=1:size(J,2)
    R2=rank(J(:,1:I));
    if R2~=R1
        depcol(i)=I;
        i=i+1;
    end
    R1=R2+1;
end
if isempty(depcol)
    app.Numberoflineralydependnetjoints.Value={'0'};
    app.Columnsthatarelinearlydependent.Value={'0'};
else
    app.Numberoflineralydependnetjoints.Value={length(depcol)};
    app.Columnsthatarelinearlydependent.Value={depcol};
end


        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            clear all
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
             app.jpconf(app.JointNoEditField.Value)= app.JointVariableValueEditField.Value;
            a=app.jpconf;
        end

        % Button pushed function: RUNButton_2
        function RUNButton_2Pushed(app, event)
            app.SingularityTextArea.Value= string(R.maniplty(app.jpconf));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DifferentialKinematicsnoDHUIFigure = uifigure('Visible', 'off');
            app.DifferentialKinematicsnoDHUIFigure.Name = 'Differential Kinematics no DH';
            app.DHTable = uitable(app.DifferentialKinematicsnoDHUIFigure);
            app.DHTable.ColumnName = {'Theta'; 'd'; 'a'; 'alpha'};
            app.DHTable.RowName = {''};
            app.INVERSEKINEMATICS = uilabel(app.DifferentialKinematicsnoDHUIFigure);
            app.INVERSEKINEMATICS.Text = 'Differential Kinematics ';
            app.AllAnglesareinDEGREELabel = uilabel(app.DifferentialKinematicsnoDHUIFigure);
            app.AllAnglesareinDEGREELabel.Text = 'All  Angles are in DEGREE ';
            app.JacobianPanel = uipanel(app.DifferentialKinematicsnoDHUIFigure);
            app.JacobianPanel.Title = 'Jacobian';
            app.TransformationTable = uitable(app.JacobianPanel);
            app.TransformationTable.ColumnName = {''};
            app.TransformationTable.RowName = {};
            app.RESETButton = uibutton(app.DifferentialKinematicsnoDHUIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.NumberoflineralydependnetjointsTextAreaLabel = uilabel(app.DifferentialKinematicsnoDHUIFigure);
            app.NumberoflineralydependnetjointsTextAreaLabel.Text = 'Number of lineraly dependnet joints:  ';
            app.Numberoflineralydependnetjoints = uitextarea(app.DifferentialKinematicsnoDHUIFigure);
            app.ColumnsthatarelinearlydependentTextAreaLabel = uilabel(app.DifferentialKinematicsnoDHUIFigure);
            app.ColumnsthatarelinearlydependentTextAreaLabel.Text = 'Columns that are linearly dependent';
            app.Columnsthatarelinearlydependent = uitextarea(app.DifferentialKinematicsnoDHUIFigure);
            app.SingularityforaGivenPosePanel = uipanel(app.DifferentialKinematicsnoDHUIFigure);
            app.SingularityforaGivenPosePanel.Title = 'Singularity for a Given Pose';
            app.JointNoEditFieldLabel = uilabel(app.SingularityforaGivenPosePanel);
            app.JointNoEditFieldLabel.Text = 'Joint No';
            app.JointNoEditField = uieditfield(app.SingularityforaGivenPosePanel, 'numeric');
            app.JointVariableValueEditFieldLabel = uilabel(app.SingularityforaGivenPosePanel);
            app.JointVariableValueEditFieldLabel.Text = 'Joint Variable Value ';
            app.JointVariableValueEditField = uieditfield(app.SingularityforaGivenPosePanel, 'numeric');
            app.UpdateButton = uibutton(app.SingularityforaGivenPosePanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.SingularityTextAreaLabel = uilabel(app.SingularityforaGivenPosePanel);
            app.SingularityTextAreaLabel.Text = 'Singularity';
            app.SingularityTextArea = uitextarea(app.SingularityforaGivenPosePanel);
            app.RUNButton_2 = uibutton(app.SingularityforaGivenPosePanel, 'push');
            app.RUNButton_2.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_2Pushed, true);
            app.RUNButton_2.Text = 'RUN';
            app.RUNButton = uibutton(app.DifferentialKinematicsnoDHUIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.LinkDefinations = uipanel(app.DifferentialKinematicsnoDHUIFigure);
            app.LinkDefinations.Title = 'Enter Link Definations one by one';
            app.LinkNo = uieditfield(app.LinkDefinations, 'numeric');
            app.LinkNoLabel = uilabel(app.LinkDefinations);
            app.LinkNoLabel.Text = 'Link No.';
            app.NumberofLinksEditFieldLabel = uilabel(app.LinkDefinations);
            app.NumberofLinksEditFieldLabel.Text = 'Number of Links';
            app.TotalLinks = uieditfield(app.LinkDefinations, 'numeric');
            app.aInput = uieditfield(app.LinkDefinations, 'numeric');
            app.LengthoflinkLabel = uilabel(app.LinkDefinations);
            app.LengthoflinkLabel.Text = 'Length of link';
            app.AnglebetweentheZaxisoftheconsecutivelinksLabel = uilabel(app.LinkDefinations);
            app.AnglebetweentheZaxisoftheconsecutivelinksLabel.Text = 'Angle between the Z axis of the consecutive links';
            app.alphaInput = uieditfield(app.LinkDefinations, 'numeric');
            app.AnglebetweentheXaxisoftheconsecutivelinksLabel = uilabel(app.LinkDefinations);
            app.AnglebetweentheXaxisoftheconsecutivelinksLabel.Text = 'Angle between the X axis of the consecutive links';
            app.thetaInput = uieditfield(app.LinkDefinations, 'numeric');
            app.dInput = uieditfield(app.LinkDefinations, 'numeric');
            app.DistancebetweenjointsinZaxisofpreviousjointLabel = uilabel(app.LinkDefinations);
            app.DistancebetweenjointsinZaxisofpreviousjointLabel.Text = 'Distance between joints in Z axis of previous joint';
            app.PrismaticJointLimitsLabel = uilabel(app.LinkDefinations);
            app.PrismaticJointLimitsLabel.Text = 'Prismatic Joint Limits';
            app.Plimmin = uieditfield(app.LinkDefinations, 'numeric');
            app.Plimmax = uieditfield(app.LinkDefinations, 'numeric');
            app.MinLabel = uilabel(app.LinkDefinations);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.LinkDefinations);
            app.MaxLabel.Text = 'Max';
            app.DHUpdate = uibutton(app.LinkDefinations, 'push');
            app.DHUpdate.ButtonPushedFcn = createCallbackFcn(app, @DHUpdateButtonPushed, true);
            app.DHUpdate.Text = 'UPDATE';
            app.Linktype = uieditfield(app.LinkDefinations, 'text');
            app.TextArea = uitextarea(app.LinkDefinations);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Enter r for revolute and p for prismatic'};
            % Show the figure after all components are created
            app.DifferentialKinematicsnoDHUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Differnetial_Kinematics_Numercial_noDH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DifferentialKinematicsnoDHUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DifferentialKinematicsnoDHUIFigure)
        end
    end
end