classdef Euler_angle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EulerAngleUIFigure        matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        EuleranglesfromRotationMatrixTab  matlab.ui.container.Tab
        RotationalMatrixPanel     matlab.ui.container.Panel
        r11                       matlab.ui.control.NumericEditField
        r21                       matlab.ui.control.NumericEditField
        r31                       matlab.ui.control.NumericEditField
        r12                       matlab.ui.control.NumericEditField
        r22                       matlab.ui.control.NumericEditField
        r32                       matlab.ui.control.NumericEditField
        r13                       matlab.ui.control.NumericEditField
        r23                       matlab.ui.control.NumericEditField
        r33                       matlab.ui.control.NumericEditField
        EularAngleTable           matlab.ui.control.Table
        FrameSelectionPanel       matlab.ui.container.Panel
        NoteSolutionforcurrentframeexistonlyifLabel  matlab.ui.control.Label
        RunButton                 matlab.ui.control.Button
        DropDown                  matlab.ui.control.DropDown
        r13andr23arenotzeroLabel  matlab.ui.control.Label
        RotationalMatrixfromEulerAngleTab  matlab.ui.container.Tab
        FrameSelectionPanel_2     matlab.ui.container.Panel
        DropDown_2                matlab.ui.control.DropDown
        RunButton_2               matlab.ui.control.Button
        RotationlMatrix           matlab.ui.control.Table
        EulerAnglesPanel          matlab.ui.container.Panel
        PhiEditFieldLabel         matlab.ui.control.Label
        PhiEditField              matlab.ui.control.NumericEditField
        ThetaEditFieldLabel       matlab.ui.control.Label
        ThetaEditField            matlab.ui.control.NumericEditField
        PsiEditFieldLabel         matlab.ui.control.Label
        PsiEditField              matlab.ui.control.NumericEditField
        EulerAngleLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run startup_rvc.m
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.DropDown.ItemsData=[1 2];
orient=app.DropDown.Value;

if orient==1

zyz = [app.r11.Value , app.r12.Value,  app.r13.Value ; app.r21.Value, app.r22.Value, app.r23.Value ; app.r31.Value, app.r32.Value, app.r33.Value];
z = atan2d(zyz(2,3),zyz(1,3));
y = atan2d((sqrt(zyz(1,3)^2+zyz(2,3)^2)),zyz(3,3));
z1 = atan2d(zyz(3,2),-zyz(3,1));
Z = atan2d(-zyz(2,3),-zyz(1,3));
Y = atan2d(-(sqrt(zyz(1,3)^2+zyz(2,3)^2)),zyz(3,3));
Z1 = atan2d(-zyz(3,2),-zyz(3,1));
R=zyz;

app.EularAngleTable.Data=[z Z; y Y; z1 Z1];

 end

if orient==2


zyx =  [app.r11.Value , app.r12.Value,  app.r13.Value ; app.r21.Value, app.r22.Value, app.r23.Value ; app.r31.Value, app.r32.Value, app.r33.Value];
z = atan2d(zyx(2,1),zyx(1,1));
y = atan2d(-zyx(3,1),sqrt(zyx(3,2)^2+zyx(3,3)^2));
x = atan2d(zyx(3,2),zyx(3,3));
Z = atan2d(-zyx(2,1),-zyx(1,1));
Y = atan2d(-zyx(3,1),-sqrt(zyx(3,2)^2+zyx(3,3)^2));
X = atan2d(-zyx(3,2),-zyx(3,3));
R=zyx;

app.EularAngleTable.Data=[z Z; y Y; x X];
end


figure(1)
axis_start = [0 0 0];
axis_end(:,1) = axis_start + [1 0 0];
axis_end(:,2) = axis_start + [0 1 0];
axis_end(:,3) = axis_start + [0 0 1];

plot3(0,0,0, 'o')
grid on;
hold on;

for i = 1:3    
    h=plot3([axis_start(1) axis_end(1,i)],...        
        [axis_start(2) axis_end(2,i)],...        
        [axis_start(3) axis_end(3,i)]);    
    if i==1        
        h.Color='red'; 
        text(axis_end(1,i),...
            axis_end(2,i),...
            axis_end(3,i),'x_A','VerticalAlignment','bottom','HorizontalAlignment','right','fontsize',16);
        
    elseif i==2        
        h.Color='green';
        text(axis_end(1,i),...
           axis_end(2,i),...
           axis_end(3,i),'y_A','VerticalAlignment','bottom','HorizontalAlignment','right','fontsize',16);
    else
        h.Color='blue';
        text(axis_end(1,i),...
           axis_end(2,i),...
           axis_end(3,i),'z_A','VerticalAlignment','bottom','HorizontalAlignment','right','fontsize',16);
    end
end

hold on
p = [0 0 0]';
axis_start = p;

figure(1)
for i=1:3    
    axis_end(:,i) = axis_start + R(:,i);
end

plot3(p(1), p(2), p(3), 'o');
grid on
hold on
for i = 1:3    
    h=plot3([axis_start(1) axis_end(1,i)],...        
        [axis_start(2) axis_end(2,i)],...        
        [axis_start(3) axis_end(3,i)]);    
    if i==1        
        h.Color='red';
        text(axis_end(1,i),...
            axis_end(2,i),...
            axis_end(3,i),'x_B','VerticalAlignment','bottom','HorizontalAlignment','right','fontsize',16);
    elseif i==2        
        h.Color='green';  
        text(axis_end(1,i),...
           axis_end(2,i),...
           axis_end(3,i),'y_B','VerticalAlignment','bottom','HorizontalAlignment','right','fontsize',16);
    else
        h.Color='blue'; 
         text(axis_end(1,i),...
           axis_end(2,i),...
           axis_end(3,i),'z_B','VerticalAlignment','bottom','HorizontalAlignment','right','fontsize',16);
    end
end
if orient=='1'
    title('ZYZ Rotation');
else
    title('ZYX Rotation');
end
    
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
app.DropDown_2.ItemsData=[1 2];
orient=app.DropDown_2.Value;

if orient==1


   z = app.PhiEditField.Value;
   y = app.ThetaEditField.Value;
   z1 = app.PsiEditField.Value;

    R =[cosd(z)*cosd(y)*cosd(z1)-sind(z)*sind(z1), -cosd(z)*cosd(y)*cosd(z1)-sind(z)*sind(z1), cosd(z)*sind(y);
             sind(z)*cosd(y)*cosd(z1)+cosd(z)*sind(z1), -sind(z)*cosd(y)*sind(z1)+cosd(z)*cosd(z1), sind(z)*sind(y);
             -sind(y)*cosd(z1), sind(y)*sind(z1) cosd(y)];
    app.RotationlMatrix.Data=R;
end

if orient==2

 z = app.PhiEditField.Value;
   y = app.ThetaEditField.Value;
   x = app.PsiEditField.Value;
R = [cosd(z)*cosd(y) cosd(z)*sind(y)*sind(x)-sind(z)*cosd(x) cosd(z)*sind(y)*cosd(x)+sind(z)*sind(x);
        sind(z)*cosd(y) sind(z)*sind(y)*sind(x)+cosd(z)*cosd(x) sind(z)*sind(y)*cosd(x)-cosd(z)*sind(x);
             -sind(y) cosd(y)*sind(x) cosd(y)*cosd(x)];
    app.RotationlMatrix.Data=R;
end


figure(1)
axis_start = [0 0 0];
axis_end(:,1) = axis_start + [1 0 0];
axis_end(:,2) = axis_start + [0 1 0];
axis_end(:,3) = axis_start + [0 0 1];

plot3(0,0,0, 'o')
grid on;
hold on;

for i = 1:3    
    h=plot3([axis_start(1) axis_end(1,i)],...        
        [axis_start(2) axis_end(2,i)],...        
        [axis_start(3) axis_end(3,i)]);    
    if i==1        
        h.Color='red'; 
        text(axis_end(1,i),...
            axis_end(2,i),...
            axis_end(3,i),'x_A','VerticalAlignment','bottom','HorizontalAlignment','right','fontsize',16);
        
    elseif i==2        
        h.Color='green';
        text(axis_end(1,i),...
           axis_end(2,i),...
           axis_end(3,i),'y_A','VerticalAlignment','bottom','HorizontalAlignment','right','fontsize',16);
    else
        h.Color='blue';
        text(axis_end(1,i),...
           axis_end(2,i),...
           axis_end(3,i),'z_A','VerticalAlignment','bottom','HorizontalAlignment','right','fontsize',16);
    end
end

hold on
p = [0 0 0]';
axis_start = p;

figure(1)
for i=1:3    
    axis_end(:,i) = axis_start + R(:,i);
end

plot3(p(1), p(2), p(3), 'o');
grid on
hold on
for i = 1:3    
    h=plot3([axis_start(1) axis_end(1,i)],...        
        [axis_start(2) axis_end(2,i)],...        
        [axis_start(3) axis_end(3,i)]);    
    if i==1        
        h.Color='red';
        text(axis_end(1,i),...
            axis_end(2,i),...
            axis_end(3,i),'x_B','VerticalAlignment','bottom','HorizontalAlignment','right','fontsize',16);
    elseif i==2        
        h.Color='green';  
        text(axis_end(1,i),...
           axis_end(2,i),...
           axis_end(3,i),'y_B','VerticalAlignment','bottom','HorizontalAlignment','right','fontsize',16);
    else
        h.Color='blue'; 
         text(axis_end(1,i),...
           axis_end(2,i),...
           axis_end(3,i),'z_B','VerticalAlignment','bottom','HorizontalAlignment','right','fontsize',16);
    end
end
if orient=='1'
    title('ZYZ Rotation');
else
    title('ZYX Rotation');
end
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EulerAngleUIFigure = uifigure('Visible', 'off');
            app.EulerAngleUIFigure.Name = 'Euler Angle';
            app.TabGroup = uitabgroup(app.EulerAngleUIFigure);
            app.EuleranglesfromRotationMatrixTab = uitab(app.TabGroup);
            app.EuleranglesfromRotationMatrixTab.Title = 'Euler angles from Rotation Matrix';
            app.RotationalMatrixPanel = uipanel(app.EuleranglesfromRotationMatrixTab);
            app.RotationalMatrixPanel.Title = 'Rotational Matrix';
            app.r11 = uieditfield(app.RotationalMatrixPanel, 'numeric');
            app.r11.Value = 1;
            app.r21 = uieditfield(app.RotationalMatrixPanel, 'numeric');
            app.r31 = uieditfield(app.RotationalMatrixPanel, 'numeric');
            app.r12 = uieditfield(app.RotationalMatrixPanel, 'numeric');
            app.r22 = uieditfield(app.RotationalMatrixPanel, 'numeric');
            app.r22.Value = 1;
            app.r32 = uieditfield(app.RotationalMatrixPanel, 'numeric');
            app.r13 = uieditfield(app.RotationalMatrixPanel, 'numeric');
            app.r23 = uieditfield(app.RotationalMatrixPanel, 'numeric');
            app.r33 = uieditfield(app.RotationalMatrixPanel, 'numeric');
            app.r33.Value = 1;
            app.EularAngleTable = uitable(app.EuleranglesfromRotationMatrixTab);
            app.EularAngleTable.ColumnName = {'Euler Angles'};
            app.EularAngleTable.RowName = {'Phi'; 'Theta'; 'Psi'};
            app.FrameSelectionPanel = uipanel(app.EuleranglesfromRotationMatrixTab);
            app.FrameSelectionPanel.Title = 'Frame Selection';
            app.NoteSolutionforcurrentframeexistonlyifLabel = uilabel(app.FrameSelectionPanel);
            app.NoteSolutionforcurrentframeexistonlyifLabel.Text = 'Note: Solution for current frame exist only if ';
            app.RunButton = uibutton(app.FrameSelectionPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.DropDown = uidropdown(app.FrameSelectionPanel);
            app.DropDown.Items = {'Current Frame (ZYZ)', 'Fixed Frame (ZYX)'};
            app.DropDown.Value = 'Current Frame (ZYZ)';
            app.r13andr23arenotzeroLabel = uilabel(app.FrameSelectionPanel);
            app.r13andr23arenotzeroLabel.Text = 'r13 and r23  are not zero ';
            app.RotationalMatrixfromEulerAngleTab = uitab(app.TabGroup);
            app.RotationalMatrixfromEulerAngleTab.Title = 'Rotational Matrix from Euler Angle';
            app.FrameSelectionPanel_2 = uipanel(app.RotationalMatrixfromEulerAngleTab);
            app.FrameSelectionPanel_2.Title = 'Frame Selection';
            app.DropDown_2 = uidropdown(app.FrameSelectionPanel_2);
            app.DropDown_2.Items = {'Current Frame (ZYZ)', 'Fixed Frame (ZYX)'};
            app.DropDown_2.Value = 'Current Frame (ZYZ)';
            app.RunButton_2 = uibutton(app.FrameSelectionPanel_2, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.RotationlMatrix = uitable(app.RotationalMatrixfromEulerAngleTab);
            app.RotationlMatrix.ColumnName = {'Rotational Matrix'};
            app.RotationlMatrix.RowName = {''};
            app.EulerAnglesPanel = uipanel(app.RotationalMatrixfromEulerAngleTab);
            app.EulerAnglesPanel.Title = 'Euler Angles';
            app.PhiEditFieldLabel = uilabel(app.EulerAnglesPanel);
            app.PhiEditFieldLabel.Text = 'Phi';
            app.PhiEditField = uieditfield(app.EulerAnglesPanel, 'numeric');
            app.ThetaEditFieldLabel = uilabel(app.EulerAnglesPanel);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.EulerAnglesPanel, 'numeric');
            app.PsiEditFieldLabel = uilabel(app.EulerAnglesPanel);
            app.PsiEditFieldLabel.Text = 'Psi';
            app.PsiEditField = uieditfield(app.EulerAnglesPanel, 'numeric');
            app.EulerAngleLabel = uilabel(app.EulerAngleUIFigure);
            app.EulerAngleLabel.Text = 'Euler Angle';
            % Show the figure after all components are created
            app.EulerAngleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Euler_angle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EulerAngleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EulerAngleUIFigure)
        end
    end
end