classdef Inverse_Kinematics_Diffrential < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InverseDifferentialKinematicsUIFigure  matlab.ui.Figure
        LinkDefinations              matlab.ui.container.Panel
        LinkNo                       matlab.ui.control.NumericEditField
        thetaInput                   matlab.ui.control.NumericEditField
        dInput                       matlab.ui.control.NumericEditField
        aInput                       matlab.ui.control.NumericEditField
        alphaInput                   matlab.ui.control.NumericEditField
        DHUpdate                     matlab.ui.control.Button
        LinkNoLabel                  matlab.ui.control.Label
        ThetaLabel                   matlab.ui.control.Label
        dLabel                       matlab.ui.control.Label
        aLabel                       matlab.ui.control.Label
        alphaLabel                   matlab.ui.control.Label
        NumberofLinksEditFieldLabel  matlab.ui.control.Label
        TotalLinks                   matlab.ui.control.NumericEditField
        Plimmin                      matlab.ui.control.NumericEditField
        Plimmax                      matlab.ui.control.NumericEditField
        PrismaticJointLimitsLabel    matlab.ui.control.Label
        MinLabel                     matlab.ui.control.Label
        MaxLabel                     matlab.ui.control.Label
        Linktype                     matlab.ui.control.EditField
        TextArea_2                   matlab.ui.control.TextArea
        INVERSEKINEMATICS            matlab.ui.control.Label
        EndPoseValues                matlab.ui.container.Panel
        WxLabel                      matlab.ui.control.Label
        WX                           matlab.ui.control.NumericEditField
        WyLabel                      matlab.ui.control.Label
        WY                           matlab.ui.control.NumericEditField
        WzLabel                      matlab.ui.control.Label
        WZ                           matlab.ui.control.NumericEditField
        VxLabel                      matlab.ui.control.Label
        PX                           matlab.ui.control.NumericEditField
        VyLabel                      matlab.ui.control.Label
        PY                           matlab.ui.control.NumericEditField
        VzLabel                      matlab.ui.control.Label
        PZ                           matlab.ui.control.NumericEditField
        RUNButton                    matlab.ui.control.Button
        ValueofLinearinmsandangularinRadsLabel  matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        JointNoEditFieldLabel        matlab.ui.control.Label
        JointNo                      matlab.ui.control.NumericEditField
        ValueEditFieldLabel          matlab.ui.control.Label
        JointVariable                matlab.ui.control.NumericEditField
        Qdot                         matlab.ui.control.Table
        DHTable                      matlab.ui.control.Table
        AnglesmustbeenteredinDEGREEUnitONLYLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        theta = [];
        a   =   [];
        d   =   [];
        alpha = [];
        INVS =  [];
        min  =  [];
        max  =  [];
        type =  [];
        jvalue = [];

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run startup_rvc.m
        end

        % Button pushed function: DHUpdate
        function DHUpdateButtonPushed(app, event)
            n = app.TotalLinks.Value;
            i = app.LinkNo.Value;
            app.theta(i) = app.thetaInput.Value;
            app.d(i) = app.dInput.Value;
            app.a(i) = app.aInput.Value;
            app.alpha(i) = app.alphaInput.Value;
            app.min(i) = app.Plimmin.Value; 
            app.max(i) = app.Plimmax.Value;
            app.type(i) = app.Linktype.Value;
            temp = (app.type);
            if i==n
                for i = 1:n
                    DH(i,:) = [(pi/180)*app.theta(i), app.d(i), app.a(i), (pi/180)*app.alpha(i)];
                end
                app.DHTable.Data = DH;
            end
               
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            i =app.JointNo.Value;
            n =  app.TotalLinks.Value;
            DH = app.DHTable.Data;
            low = app.min;
            high = app.max;
            temp = round(app.type)
            Vx = app.PX.Value;
            Vy = app.PY.Value;
            Vz = app.PZ.Value;
            Wx   = app.WX.Value;
            Wy = app.WY.Value;
            Wz   = app.WZ.Value;
            app.jvalue(i) = app.JointVariable.Value;
            if i == n
            q0 = app.jvalue
           [q_dot] = Inverse_Diffrential(n, temp, DH, low, high, Vx, Vy, Vz, Wx, Wy, Wz, q0)
           app.Qdot.Data = q_dot;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InverseDifferentialKinematicsUIFigure = uifigure('Visible', 'off');
            app.InverseDifferentialKinematicsUIFigure.Name = 'Inverse Differential Kinematics';
            app.LinkDefinations = uipanel(app.InverseDifferentialKinematicsUIFigure);
            app.LinkDefinations.Title = 'Enter Link Definitions one by one';
            app.LinkNo = uieditfield(app.LinkDefinations, 'numeric');
            app.thetaInput = uieditfield(app.LinkDefinations, 'numeric');
            app.dInput = uieditfield(app.LinkDefinations, 'numeric');
            app.aInput = uieditfield(app.LinkDefinations, 'numeric');
            app.alphaInput = uieditfield(app.LinkDefinations, 'numeric');
            app.DHUpdate = uibutton(app.LinkDefinations, 'push');
            app.DHUpdate.ButtonPushedFcn = createCallbackFcn(app, @DHUpdateButtonPushed, true);
            app.DHUpdate.Text = 'UPDATE';
            app.LinkNoLabel = uilabel(app.LinkDefinations);
            app.LinkNoLabel.Text = 'Link No.';
            app.ThetaLabel = uilabel(app.LinkDefinations);
            app.ThetaLabel.Text = 'Theta';
            app.dLabel = uilabel(app.LinkDefinations);
            app.dLabel.Text = 'd';
            app.aLabel = uilabel(app.LinkDefinations);
            app.aLabel.Text = 'a';
            app.alphaLabel = uilabel(app.LinkDefinations);
            app.alphaLabel.Text = 'alpha';
            app.NumberofLinksEditFieldLabel = uilabel(app.LinkDefinations);
            app.NumberofLinksEditFieldLabel.Text = 'Number of Links';
            app.TotalLinks = uieditfield(app.LinkDefinations, 'numeric');
            app.Plimmin = uieditfield(app.LinkDefinations, 'numeric');
            app.Plimmax = uieditfield(app.LinkDefinations, 'numeric');
            app.PrismaticJointLimitsLabel = uilabel(app.LinkDefinations);
            app.PrismaticJointLimitsLabel.Text = 'Prismatic Joint Limits';
            app.MinLabel = uilabel(app.LinkDefinations);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.LinkDefinations);
            app.MaxLabel.Text = 'Max';
            app.Linktype = uieditfield(app.LinkDefinations, 'text');
            app.TextArea_2 = uitextarea(app.LinkDefinations);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Enter r for revolute and p for prismatic'};
            app.INVERSEKINEMATICS = uilabel(app.InverseDifferentialKinematicsUIFigure);
            app.INVERSEKINEMATICS.Text = 'INVERSE DIFFERENTIAL KINEMATICS';
            app.EndPoseValues = uipanel(app.InverseDifferentialKinematicsUIFigure);
            app.EndPoseValues.Title = 'Values of Robot''s End Pose';
            app.WxLabel = uilabel(app.EndPoseValues);
            app.WxLabel.Text = 'Wx';
            app.WX = uieditfield(app.EndPoseValues, 'numeric');
            app.WyLabel = uilabel(app.EndPoseValues);
            app.WyLabel.Text = 'Wy';
            app.WY = uieditfield(app.EndPoseValues, 'numeric');
            app.WzLabel = uilabel(app.EndPoseValues);
            app.WzLabel.Text = 'Wz';
            app.WZ = uieditfield(app.EndPoseValues, 'numeric');
            app.VxLabel = uilabel(app.EndPoseValues);
            app.VxLabel.Text = 'Vx';
            app.PX = uieditfield(app.EndPoseValues, 'numeric');
            app.VyLabel = uilabel(app.EndPoseValues);
            app.VyLabel.Text = 'Vy';
            app.PY = uieditfield(app.EndPoseValues, 'numeric');
            app.VzLabel = uilabel(app.EndPoseValues);
            app.VzLabel.Text = 'Vz';
            app.PZ = uieditfield(app.EndPoseValues, 'numeric');
            app.RUNButton = uibutton(app.EndPoseValues, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.ValueofLinearinmsandangularinRadsLabel = uilabel(app.EndPoseValues);
            app.ValueofLinearinmsandangularinRadsLabel.Text = 'Value of Linear in m/s and angular in Rad/s';
            app.TextArea = uitextarea(app.EndPoseValues);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Enter Value for Revolute in Radians and Prismatic in m.'; 'after entering each of the above values press the below button'; 'Value of variables to left should be entered only once'};
            app.JointNoEditFieldLabel = uilabel(app.EndPoseValues);
            app.JointNoEditFieldLabel.Text = 'Joint No.';
            app.JointNo = uieditfield(app.EndPoseValues, 'numeric');
            app.ValueEditFieldLabel = uilabel(app.EndPoseValues);
            app.ValueEditFieldLabel.Text = 'Value';
            app.JointVariable = uieditfield(app.EndPoseValues, 'numeric');
            app.Qdot = uitable(app.InverseDifferentialKinematicsUIFigure);
            app.Qdot.ColumnName = {'VALUE OF JOINT VELOCITIES iIN ORDER OF 1 TO N'};
            app.Qdot.RowName = {};
            app.DHTable = uitable(app.InverseDifferentialKinematicsUIFigure);
            app.DHTable.ColumnName = {'Theta'; 'd'; 'a'; 'alpha'};
            app.DHTable.RowName = {''};
            app.AnglesmustbeenteredinDEGREEUnitONLYLabel = uilabel(app.InverseDifferentialKinematicsUIFigure);
            app.AnglesmustbeenteredinDEGREEUnitONLYLabel.Text = ' Angles must be entered in DEGREE Unit ONLY';
            % Show the figure after all components are created
            app.InverseDifferentialKinematicsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Inverse_Kinematics_Diffrential

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InverseDifferentialKinematicsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InverseDifferentialKinematicsUIFigure)
        end
    end
end