classdef Main_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                      matlab.ui.Figure
        IndexPanel                      matlab.ui.container.Panel
        RotationMatrixButton            matlab.ui.control.Button
        DescriptionofFrameButton        matlab.ui.control.Button
        TransformOperatorButton         matlab.ui.control.Button
        TransformMappingButton          matlab.ui.control.Button
        HomogenousTransforamtionButton  matlab.ui.control.Button
        EulerAnglesButton               matlab.ui.control.Button
        ForwardKinematicsButton         matlab.ui.control.Button
        InverseKinematicsButton         matlab.ui.control.Button
        InverseDiffrentialKinematicsButton  matlab.ui.control.Button
        InverseKinematicsUsingJacobianButton  matlab.ui.control.Button
        DifferentialKinematicsNumericalButton  matlab.ui.control.Button
        WorkspaceButton                 matlab.ui.control.Button
        ControlButton                   matlab.ui.control.Button
        DynamicsButton                  matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        DoyouknowDHparameterofRobotDropDownLabel  matlab.ui.control.Label
        DHinfo                          matlab.ui.control.DropDown
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run startup_rvc.m
        end

        % Button pushed function: RotationMatrixButton
        function RotationMatrixButtonPushed(app, event)
            Rotation_matrix_final
        end

        % Button pushed function: DescriptionofFrameButton
        function DescriptionofFrameButtonPushed(app, event)
            Description_of_Frame_final
        end

        % Button pushed function: TransformOperatorButton
        function TransformOperatorButtonPushed(app, event)
            Transform_operator_final
        end

        % Button pushed function: ForwardKinematicsButton
        function ForwardKinematicsButtonPushed(app, event)
            if  app.DHinfo.Value == 'YES'
               Forward_Kinematics
            else
               Forward_Kinematics_noDH 
            end
        end

        % Button pushed function: InverseKinematicsButton
        function InverseKinematicsButtonPushed(app, event)
            if  app.DHinfo.Value == 'YES'
                Inverse_Kinematics
            else
                Inverse_Kinematics_noDH
            end
        end

        % Button pushed function: InverseDiffrentialKinematicsButton
        function InverseDiffrentialKinematicsButtonPushed(app, event)
            if  app.DHinfo.Value == 'YES'
                Inverse_Kinematics_Diffrential
            else
                Inverse_Kinematics_Diffrential_noDH
            end
            
        end

        % Button pushed function: 
        % InverseKinematicsUsingJacobianButton
        function InverseKinematicsUsingJacobianButtonPushed(app, event)
            if  app.DHinfo.Value == 'YES'
                Inverse_Kinematics_Jac
            else
                Inverse_Kinematics_Jac_noDH
            end            
        end

        % Button pushed function: 
        % DifferentialKinematicsNumericalButton
        function DifferentialKinematicsNumericalButtonPushed(app, event)
             if  app.DHinfo.Value == 'YES'
                Differnetial_Kinematics_Numercial
            else
                Differnetial_Kinematics_Numercial_noDH
            end   
        end

        % Button pushed function: EulerAnglesButton
        function EulerAnglesButtonPushed(app, event)
            Euler_angle
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'Main Window';
            app.IndexPanel = uipanel(app.MainWindow);
            app.IndexPanel.Title = 'Index';
            app.RotationMatrixButton = uibutton(app.IndexPanel, 'push');
            app.RotationMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @RotationMatrixButtonPushed, true);
            app.RotationMatrixButton.Text = 'Rotation Matrix';
            app.DescriptionofFrameButton = uibutton(app.IndexPanel, 'push');
            app.DescriptionofFrameButton.ButtonPushedFcn = createCallbackFcn(app, @DescriptionofFrameButtonPushed, true);
            app.DescriptionofFrameButton.Text = 'Description of Frame';
            app.TransformOperatorButton = uibutton(app.IndexPanel, 'push');
            app.TransformOperatorButton.ButtonPushedFcn = createCallbackFcn(app, @TransformOperatorButtonPushed, true);
            app.TransformOperatorButton.Text = 'Transform Operator';
            app.TransformMappingButton = uibutton(app.IndexPanel, 'push');
            app.TransformMappingButton.Text = 'Transform Mapping';
            app.HomogenousTransforamtionButton = uibutton(app.IndexPanel, 'push');
            app.HomogenousTransforamtionButton.Text = 'Homogenous Transforamtion';
            app.EulerAnglesButton = uibutton(app.IndexPanel, 'push');
            app.EulerAnglesButton.ButtonPushedFcn = createCallbackFcn(app, @EulerAnglesButtonPushed, true);
            app.EulerAnglesButton.Text = 'Euler Angles';
            app.ForwardKinematicsButton = uibutton(app.IndexPanel, 'push');
            app.ForwardKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardKinematicsButtonPushed, true);
            app.ForwardKinematicsButton.Text = 'Forward Kinematics';
            app.InverseKinematicsButton = uibutton(app.IndexPanel, 'push');
            app.InverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @InverseKinematicsButtonPushed, true);
            app.InverseKinematicsButton.Text = 'Inverse Kinematics';
            app.InverseDiffrentialKinematicsButton = uibutton(app.IndexPanel, 'push');
            app.InverseDiffrentialKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @InverseDiffrentialKinematicsButtonPushed, true);
            app.InverseDiffrentialKinematicsButton.Text = 'Inverse Diffrential Kinematics';
            app.InverseKinematicsUsingJacobianButton = uibutton(app.IndexPanel, 'push');
            app.InverseKinematicsUsingJacobianButton.ButtonPushedFcn = createCallbackFcn(app, @InverseKinematicsUsingJacobianButtonPushed, true);
            app.InverseKinematicsUsingJacobianButton.Text = 'Inverse Kinematics Using Jacobian';
            app.DifferentialKinematicsNumericalButton = uibutton(app.IndexPanel, 'push');
            app.DifferentialKinematicsNumericalButton.ButtonPushedFcn = createCallbackFcn(app, @DifferentialKinematicsNumericalButtonPushed, true);
            app.DifferentialKinematicsNumericalButton.Text = 'Differential Kinematics (Numerical)';
            app.WorkspaceButton = uibutton(app.IndexPanel, 'push');
            app.WorkspaceButton.Text = 'Workspace';
            app.ControlButton = uibutton(app.IndexPanel, 'push');
            app.ControlButton.Text = 'Control';
            app.DynamicsButton = uibutton(app.IndexPanel, 'push');
            app.DynamicsButton.Text = 'Dynamics';
            app.TextArea = uitextarea(app.MainWindow);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'ROBOTICS TOOLBOX'; ''};
            app.TextArea_2 = uitextarea(app.MainWindow);
            app.TextArea_2.Value = {'Ravendra Raghavendra'; 'Malay Nagda'; 'Laukik Mujumdar'; 'Amaan Khan'; 'Tanmay Dhanote'};
            app.DoyouknowDHparameterofRobotDropDownLabel = uilabel(app.MainWindow);
            app.DoyouknowDHparameterofRobotDropDownLabel.Text = 'Do you know DH parameter of Robot';
            app.DHinfo = uidropdown(app.MainWindow);
            app.DHinfo.Items = {'YES', 'NO.'};
            app.DHinfo.Value = 'YES';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_Window

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end