classdef Manipulator_Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManipulatorControlUIFigure  matlab.ui.Figure
        LinkParametersPanel         matlab.ui.container.Panel
        UPDATE                      matlab.ui.control.Button
        LinkmassEditFieldLabel      matlab.ui.control.Label
        link_mass                   matlab.ui.control.NumericEditField
        CGxEditFieldLabel           matlab.ui.control.Label
        cgx                         matlab.ui.control.NumericEditField
        CGyEditFieldLabel           matlab.ui.control.Label
        cgy                         matlab.ui.control.NumericEditField
        CGzEditFieldLabel           matlab.ui.control.Label
        cgz                         matlab.ui.control.NumericEditField
        IxxEditFieldLabel           matlab.ui.control.Label
        Ixx                         matlab.ui.control.NumericEditField
        IxyEditFieldLabel           matlab.ui.control.Label
        Ixy                         matlab.ui.control.NumericEditField
        IyyEditFieldLabel           matlab.ui.control.Label
        Iyy                         matlab.ui.control.NumericEditField
        IxzEditFieldLabel           matlab.ui.control.Label
        Ixz                         matlab.ui.control.NumericEditField
        IyzEditField_2Label         matlab.ui.control.Label
        Iyz                         matlab.ui.control.NumericEditField
        IzzEditFieldLabel           matlab.ui.control.Label
        Izz                         matlab.ui.control.NumericEditField
        JmEditFieldLabel            matlab.ui.control.Label
        Jm                          matlab.ui.control.NumericEditField
        GearRatioEditFieldLabel     matlab.ui.control.Label
        G                           matlab.ui.control.NumericEditField
        LinkLengthLabel             matlab.ui.control.Label
        Linklength                  matlab.ui.control.NumericEditField
        gxLabel                     matlab.ui.control.Label
        gx                          matlab.ui.control.NumericEditField
        gyLabel                     matlab.ui.control.Label
        gy                          matlab.ui.control.NumericEditField
        gzLabel                     matlab.ui.control.Label
        gz                          matlab.ui.control.NumericEditField
        LinkNumberLabel             matlab.ui.control.Label
        LinkNo                      matlab.ui.control.NumericEditField
        InitialConditionPanel       matlab.ui.container.Panel
        q1Label                     matlab.ui.control.Label
        q1                          matlab.ui.control.NumericEditField
        q2Label                     matlab.ui.control.Label
        q2                          matlab.ui.control.NumericEditField
        qd1Label                    matlab.ui.control.Label
        qd1                         matlab.ui.control.NumericEditField
        qd2Label                    matlab.ui.control.Label
        qd2                         matlab.ui.control.NumericEditField
        JointVariablesLabel         matlab.ui.control.Label
        JointRatesLabel             matlab.ui.control.Label
        ForcebyEndEffectorLabel     matlab.ui.control.Label
        FxLabel                     matlab.ui.control.Label
        Fx                          matlab.ui.control.NumericEditField
        FyLabel                     matlab.ui.control.Label
        Fy                          matlab.ui.control.NumericEditField
        XLabel                      matlab.ui.control.Label
        X                           matlab.ui.control.NumericEditField
        YLabel                      matlab.ui.control.Label
        Y                           matlab.ui.control.NumericEditField
        DesiredPositionLabel        matlab.ui.control.Label
        TimeLabel                   matlab.ui.control.Label
        Time                        matlab.ui.control.NumericEditField
        MotionControl               matlab.ui.control.Button
        INRADIANSLabel              matlab.ui.control.Label
        ForceControl                matlab.ui.control.Button
        InputforforcecontrolLabel   matlab.ui.control.Label
        XVT                         matlab.ui.control.UIAxes
        YVT                         matlab.ui.control.UIAxes
        QDvt                        matlab.ui.control.UIAxes
        QVT                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        g = [];
        R;
        L1;
        L2;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run startup_rvc.m
        end

        % Button pushed function: UPDATE
        function UPDATEButtonPushed(app, event)
           i =app.LinkNo.Value;
            if i == 1 
                app.L1     = Link('d', 0, 'a', app.Linklength.Value, 'alpha', 0);
                app.L1.m   = app.link_mass.Value;
                app.L1.r   = [app.cgx.Value app.cgy.Value app.cgz.Value];
                app.L1.I   = [app.Ixx.Value -app.Ixy.Value -app.Ixz.Value; -app.Ixy.Value app.Iyy.Value -app.Ixz.Value; -app.Ixy.Value -app.Iyz.Value app.Izz.Value];
                app.L1.Jm  = app.Jm.Value;
                app.L1.G   = app.G.Value;
            end
            if i == 2
                app.L2     = Link('d', 0, 'a', app.Linklength.Value, 'alpha', 0);
                app.L2.m   = app.link_mass.Value;
                app.L2.r   = [app.cgx.Value app.cgy.Value app.cgz.Value];
                app.L2.I   = [app.Ixx.Value -app.Ixy.Value app.Ixz.Value; app.Ixy.Value app.Iyy.Value -app.Ixz.Value; -app.Ixy.Value -app.Iyz.Value app.Izz.Value];
                app.L2.Jm  = app.Jm.Value;
                app.L2.G   = app.G.Value;
            end
             
            if i == 2
                app.R = SerialLink([app.L1 app.L2]);
                app.g=[app.gx.Value, app.gy.Value app.gz.Value];
                app.R.gravity=app.g;
                app.R
            end
        end

        % Button pushed function: MotionControl
        function MotionControlButtonPushed(app, event)
            q0  = [app.q1.Value app.q2.Value];
            qd0 = [app.qd1.Value app.qd2.Value];
            app.R.fast=0;
            xd=[app.X.Value;app.Y.Value]
            tf =app.Time.Value;
            qt=q0;
            x0=app.R.fkine(q0);

            x0=x0(1:2,end);


%User inputs
            qdt=qd0;

%User inputs. Default values are one each
            kp=10;
            kd=10;


            dt=0.01;

n_steps=floor(tf/dt);

for i=1:n_steps
    %keyboard();
    J=app.R.jacob0(qt(i,:));
    J=J(1:2,1:2);
    
    J_dot=app.R.jacob_dot(qt(i,:),qdt(i,:));
    J_dot=J_dot(1:2); %This is already multiplied with the joint rates
    
    xt=app.R.fkine(qt(i,:));
    xt=xt(1:2,end);
    
    xdt=J*qdt(i,:)';
    
    y=inv(J)*(-kd*xdt+kp*(xd-xt)-J_dot);
    
    future_q=qt(i,:)+qdt(i,:)*dt;
    
    B=app.R.inertia(qt(i,:));
    B_dot=(app.R.inertia(future_q)-app.R.inertia(qt(i,:)))/dt;
    
    C=app.R.coriolis(qt(i,:),qdt(i,:));
    
    app.g=app.R.gravjac(qt(i,:));
    
    n=C*qdt(i,:)'+app.g'; %Refer to 8.56 in the book
    
    u=B*y+n;
    
    
    xk(:,i)=xt;
    
    %keyboard()
    qdd=inv(B)*(u-n); %From the robot dynamic equation
    qt(i+1,:)=qt(i,:)+qdt(i,:)*dt;
    qdt(i+1,:)=qdt(i,:)+qdd'*dt;
end
hold(app.QVT)
hold(app.QDvt)
plot(app.XVT, 0:dt:tf-dt, xk(1,:))
plot(app.YVT, 0:dt:tf-dt, xk(2,:))
plot(app.QVT, 0:dt:tf,qt(:,1))
plot(app.QDvt, 0:dt:tf,qdt(:,1))

plot(app.QVT, 0:dt:tf,qt(:,2))
plot(app.QDvt, 0:dt:tf,qdt(:,2))

        end

        % Button pushed function: ForceControl
        function ForceControlButtonPushed(app, event)
            q0  = [app.q1.Value app.q2.Value];
            qd0 = [app.qd1.Value app.qd2.Value];
            app.R.fast=0;
            xd=[app.X.Value;app.Y.Value];
            tf = app.Time.Value;
            he = [app.Fx.Value app.Fy.Value];
            qt=q0;
            x0=app.R.fkine(q0);
            x0=x0(1:2,end);
            qdt=qd0;
            kp=700;
            kd=3000;
            tf= app.Time.Value;
            dt=0.01;

            n_steps=floor(tf/dt);

for i=1:n_steps

    %keyboard();
    J=app.R.jacob0(qt(i,:));
    J=J(1:2,1:2);
    
    J_dot=app.R.jacob_dot(qt(i,:),qdt(i,:));
    J_dot=J_dot(1:2); %This is already multiplied with the joint rates
    
    xt=app.R.fkine(qt(i,:));
    xt=xt(1:2,end);
    
    xdt=J*qdt(i,:)';
    
    %y=inv(J)*(-kd*xdt+kp*(xd-xt)-J_dot);
    
    future_q=qt(i,:)+qdt(i,:)*dt;
    
    B=app.R.inertia(qt(i,:));
    B_dot=(app.R.inertia(future_q)-app.R.inertia(qt(i,:)))/dt;
    
    C=app.R.coriolis(qt(i,:),qdt(i,:));
    
    app.g=app.R.gravjac(qt(i,:));
    
    n=C*qdt(i,:)'+app.g'; %Refer to 8.56 in the book
    
%     keyboard()
    u=app.g'+J'*kp*(xd-xt)-J'*kd*J*qdt(i,:)';
    %he = kp*(xd-xt);
    
    xk(:,i)=xt;
    
    
    qdd=pinv(B)*(u-n-J'*he'); %From the robot dynamic equation
    qt(i+1,:)=qt(i,:)+qdt(i,:)*dt;
    qdt(i+1,:)=qdt(i,:)+qdd'*dt;
end
hold(app.QVT)
hold(app.QDvt)
plot(app.XVT, 0:dt:tf-dt, xk(1,:))
plot(app.YVT, 0:dt:tf-dt, xk(2,:))
plot(app.QVT, 0:dt:tf,qt(:,1))
plot(app.QDvt, 0:dt:tf,qdt(:,1))

plot(app.QVT, 0:dt:tf,qt(:,2))
plot(app.QDvt, 0:dt:tf,qdt(:,2))


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManipulatorControlUIFigure = uifigure('Visible', 'off');
            app.ManipulatorControlUIFigure.Name = 'Manipulator Control';
            app.LinkParametersPanel = uipanel(app.ManipulatorControlUIFigure);
            app.LinkParametersPanel.Title = 'Link Parameters';
            app.UPDATE = uibutton(app.LinkParametersPanel, 'push');
            app.UPDATE.ButtonPushedFcn = createCallbackFcn(app, @UPDATEButtonPushed, true);
            app.UPDATE.Text = 'Update';
            app.LinkmassEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.LinkmassEditFieldLabel.Text = 'Link mass';
            app.link_mass = uieditfield(app.LinkParametersPanel, 'numeric');
            app.CGxEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.CGxEditFieldLabel.Text = 'CG x';
            app.cgx = uieditfield(app.LinkParametersPanel, 'numeric');
            app.CGyEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.CGyEditFieldLabel.Text = 'CG y';
            app.cgy = uieditfield(app.LinkParametersPanel, 'numeric');
            app.CGzEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.CGzEditFieldLabel.Text = 'CG z';
            app.cgz = uieditfield(app.LinkParametersPanel, 'numeric');
            app.IxxEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.IxxEditFieldLabel.Text = 'Ixx';
            app.Ixx = uieditfield(app.LinkParametersPanel, 'numeric');
            app.IxyEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.IxyEditFieldLabel.Text = 'Ixy';
            app.Ixy = uieditfield(app.LinkParametersPanel, 'numeric');
            app.IyyEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.IyyEditFieldLabel.Text = 'Iyy';
            app.Iyy = uieditfield(app.LinkParametersPanel, 'numeric');
            app.IxzEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.IxzEditFieldLabel.Text = 'Ixz';
            app.Ixz = uieditfield(app.LinkParametersPanel, 'numeric');
            app.IyzEditField_2Label = uilabel(app.LinkParametersPanel);
            app.IyzEditField_2Label.Text = 'Iyz';
            app.Iyz = uieditfield(app.LinkParametersPanel, 'numeric');
            app.IzzEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.IzzEditFieldLabel.Text = 'Izz';
            app.Izz = uieditfield(app.LinkParametersPanel, 'numeric');
            app.JmEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.JmEditFieldLabel.Text = 'Jm';
            app.Jm = uieditfield(app.LinkParametersPanel, 'numeric');
            app.GearRatioEditFieldLabel = uilabel(app.LinkParametersPanel);
            app.GearRatioEditFieldLabel.Text = 'Gear Ratio';
            app.G = uieditfield(app.LinkParametersPanel, 'numeric');
            app.LinkLengthLabel = uilabel(app.LinkParametersPanel);
            app.LinkLengthLabel.Text = 'Link Length';
            app.Linklength = uieditfield(app.LinkParametersPanel, 'numeric');
            app.gxLabel = uilabel(app.LinkParametersPanel);
            app.gxLabel.Text = 'gx';
            app.gx = uieditfield(app.LinkParametersPanel, 'numeric');
            app.gyLabel = uilabel(app.LinkParametersPanel);
            app.gyLabel.Text = 'gy';
            app.gy = uieditfield(app.LinkParametersPanel, 'numeric');
            app.gzLabel = uilabel(app.LinkParametersPanel);
            app.gzLabel.Text = 'gz';
            app.gz = uieditfield(app.LinkParametersPanel, 'numeric');
            app.LinkNumberLabel = uilabel(app.LinkParametersPanel);
            app.LinkNumberLabel.Text = 'Link Number';
            app.LinkNo = uieditfield(app.LinkParametersPanel, 'numeric');
            app.LinkNo.Value = 1;
            app.InitialConditionPanel = uipanel(app.ManipulatorControlUIFigure);
            app.InitialConditionPanel.Title = 'Initial Condition';
            app.q1Label = uilabel(app.InitialConditionPanel);
            app.q1Label.Text = 'q1';
            app.q1 = uieditfield(app.InitialConditionPanel, 'numeric');
            app.q2Label = uilabel(app.InitialConditionPanel);
            app.q2Label.Text = 'q2';
            app.q2 = uieditfield(app.InitialConditionPanel, 'numeric');
            app.qd1Label = uilabel(app.InitialConditionPanel);
            app.qd1Label.Text = 'qd1';
            app.qd1 = uieditfield(app.InitialConditionPanel, 'numeric');
            app.qd2Label = uilabel(app.InitialConditionPanel);
            app.qd2Label.Text = 'qd2';
            app.qd2 = uieditfield(app.InitialConditionPanel, 'numeric');
            app.JointVariablesLabel = uilabel(app.InitialConditionPanel);
            app.JointVariablesLabel.Text = 'Joint Variables';
            app.JointRatesLabel = uilabel(app.InitialConditionPanel);
            app.JointRatesLabel.Text = 'Joint Rates';
            app.ForcebyEndEffectorLabel = uilabel(app.InitialConditionPanel);
            app.ForcebyEndEffectorLabel.Text = 'Force by End-Effector';
            app.FxLabel = uilabel(app.InitialConditionPanel);
            app.FxLabel.Text = 'Fx';
            app.Fx = uieditfield(app.InitialConditionPanel, 'numeric');
            app.FyLabel = uilabel(app.InitialConditionPanel);
            app.FyLabel.Text = 'Fy';
            app.Fy = uieditfield(app.InitialConditionPanel, 'numeric');
            app.XLabel = uilabel(app.InitialConditionPanel);
            app.XLabel.Text = 'X';
            app.X = uieditfield(app.InitialConditionPanel, 'numeric');
            app.YLabel = uilabel(app.InitialConditionPanel);
            app.YLabel.Text = 'Y';
            app.Y = uieditfield(app.InitialConditionPanel, 'numeric');
            app.DesiredPositionLabel = uilabel(app.InitialConditionPanel);
            app.DesiredPositionLabel.Text = 'Desired Position';
            app.TimeLabel = uilabel(app.InitialConditionPanel);
            app.TimeLabel.Text = 'Time';
            app.Time = uieditfield(app.InitialConditionPanel, 'numeric');
            app.MotionControl = uibutton(app.InitialConditionPanel, 'push');
            app.MotionControl.ButtonPushedFcn = createCallbackFcn(app, @MotionControlButtonPushed, true);
            app.MotionControl.Text = 'MOTION';
            app.INRADIANSLabel = uilabel(app.InitialConditionPanel);
            app.INRADIANSLabel.Text = 'IN RADIANS';
            app.ForceControl = uibutton(app.InitialConditionPanel, 'push');
            app.ForceControl.ButtonPushedFcn = createCallbackFcn(app, @ForceControlButtonPushed, true);
            app.ForceControl.Text = 'FORCE (Compliance)';
            app.InputforforcecontrolLabel = uilabel(app.InitialConditionPanel);
            app.InputforforcecontrolLabel.Text = '(Input for force control)';
            app.XVT = uiaxes(app.ManipulatorControlUIFigure);
            title(app.XVT, 'X vs Time')
            xlabel(app.XVT, 'Time')
            ylabel(app.XVT, 'X position')
            app.YVT = uiaxes(app.ManipulatorControlUIFigure);
            title(app.YVT, 'Y vs Time')
            xlabel(app.YVT, 'Time')
            ylabel(app.YVT, 'Y position')
            app.QDvt = uiaxes(app.ManipulatorControlUIFigure);
            title(app.QDvt, 'Qd vs Time')
            xlabel(app.QDvt, 'Time')
            ylabel(app.QDvt, 'Joint rate')
            app.QVT = uiaxes(app.ManipulatorControlUIFigure);
            title(app.QVT, 'Q vs Time')
            xlabel(app.QVT, 'Time')
            ylabel(app.QVT, 'Joint Variable')
            % Show the figure after all components are created
            app.ManipulatorControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Manipulator_Control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ManipulatorControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManipulatorControlUIFigure)
        end
    end
end