classdef Rotation_matrix_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RotationaMatrix               matlab.ui.Figure
        NumberofRotationalTransforamtionEditFieldLabel  matlab.ui.control.Label
        NumberofRotationalTransforamtion  matlab.ui.control.NumericEditField
        CurrentFrameButton            matlab.ui.control.Button
        FixedFrameButton              matlab.ui.control.Button
        RMtable                       matlab.ui.control.Table
        AATable                       matlab.ui.control.Table
        AngleEditFieldLabel           matlab.ui.control.Label
        AngleEditField                matlab.ui.control.NumericEditField
        AxisEditFieldLabel            matlab.ui.control.Label
        AxisEditField                 matlab.ui.control.EditField
        RotationNumberEditFieldLabel  matlab.ui.control.Label
        RotationNumberEditField       matlab.ui.control.NumericEditField
        UpdateButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        T={} % Description
        A={} % Description   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CurrentFrameButton, RMtable
        function CurrentFrameButtonPushed(app, event)

NRT= app.NumberofRotationalTransforamtion.Value;

syms x y z
theta=ones(NRT,1);
r=eye(3);
P0=zeros(1,3);
R=eye(3);

for i=1:NRT   
theta(i)=cell2mat(app.T(i));
Axis(i)=app.A(i);
k(i)=app.A(i);
if k(i)==x
    k1=1;
    k2=0;
    k3=0;
end
if k(i)==y
    k1=0;        
    k2=1;
    k3=0;
end
if k(i)==z
    k1=0;
    k2=0;
    k3=1;
end
% k1=k(1,1);
% k2=k(2,1);
% k3=k(3,1);
st=sind(theta(i));
ct=cosd(theta(i));
vt=(1-ct);

r1=[k1^2*vt+ct        k1*k2*vt-k3*st    k1*k3*vt+k2*st ;
   k1*k2*vt+k3*st    k2^2*vt+ct        k2*k3*vt-k1*st ;
   k1*k3*vt-k2*st    k2*k3*vt+k1*st    k3^2*vt+ct ];
eval(sprintf('E%d = r1;',i));
r=r1;
eval(sprintf('E%d',i))
R=R*r1;    
Q1=rotm2quat(R);
 eval(sprintf('QD%d = Q1;',i));
  q=Q1;
 eval(sprintf('QD%d',i))
end
figure("Name",'Rotational Transforamtion');
grid on
plotTransforms(P0,[1 0 0 0]) ;
hold on
plotTransforms(P0,q) ;
xlabel('X-axis Red');
ylabel('Y-axis Green');
zlabel('Z-axis Blue');
disp(['Rotational Matrix from 0 to ',num2str(i)] )
disp(R)
app.RMtable.Data=R;
        end

        % Button pushed function: FixedFrameButton
        function FixedFrameButtonPushed(app, event)

NRT= app.NumberofRotationalTransforamtion.Value;

syms x y z
theta=ones(NRT,1);
r=eye(3);
P0=zeros(1,3);
R=eye(3);

for i=1:NRT   
theta(i)=cell2mat(app.T(i));
Axis(i)=app.A(i);
k(i)=app.A(i);
if k(i)==x
    k1=1;
    k2=0;
    k3=0;
end
if k(i)==y
    k1=0;        
    k2=1;
    k3=0;
end
if k(i)==z
    k1=0;
    k2=0;
    k3=1;
end
% k1=k(1,1);
% k2=k(2,1);
% k3=k(3,1);
st=sind(theta(i));
ct=cosd(theta(i));
vt=(1-ct);

r1=[k1^2*vt+ct        k1*k2*vt-k3*st    k1*k3*vt+k2*st ;
   k1*k2*vt+k3*st    k2^2*vt+ct        k2*k3*vt-k1*st ;
   k1*k3*vt-k2*st    k2*k3*vt+k1*st    k3^2*vt+ct ];
eval(sprintf('E%d = r1;',i));
r=r1;
eval(sprintf('E%d',i))
R=r1*R;    
Q1=rotm2quat(R);
 eval(sprintf('QD%d = Q1;',i));
  q=Q1;
 eval(sprintf('QD%d',i))
end
figure("Name",'Rotational Transforamtion');
grid on
plotTransforms(P0,[1 0 0 0]) ;
hold on
plotTransforms(P0,q) ;
xlabel('X-axis Red');
ylabel('Y-axis Green');
zlabel('Z-axis Blue');
disp(['Rotational Matrix from 0 to ',num2str(i)] )
disp(R)
app.RMtable.Data=R;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
N= app.NumberofRotationalTransforamtion.Value;
%NRT=str2double(inputdlg('Number of Rotational Transformation ')); 
%syms x y z 
i=app.RotationNumberEditField.Value;
t={app.AngleEditField.Value};
a={app.AxisEditField.Value};
app.T(i)= [t];
app.A(i)= (a);
x=[app.A' app.T'];
app.AATable.Data=x;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RotationaMatrix = uifigure('Visible', 'off');
            app.RotationaMatrix.Name = 'Rotational Matrix';
            app.NumberofRotationalTransforamtionEditFieldLabel = uilabel(app.RotationaMatrix);
            app.NumberofRotationalTransforamtionEditFieldLabel.Text = 'Number of Rotational Transforamtion';
            app.NumberofRotationalTransforamtion = uieditfield(app.RotationaMatrix, 'numeric');
            app.CurrentFrameButton = uibutton(app.RotationaMatrix, 'push');
            app.CurrentFrameButton.ButtonPushedFcn = createCallbackFcn(app, @CurrentFrameButtonPushed, true);
            app.CurrentFrameButton.Text = 'Current Frame';
            app.FixedFrameButton = uibutton(app.RotationaMatrix, 'push');
            app.FixedFrameButton.ButtonPushedFcn = createCallbackFcn(app, @FixedFrameButtonPushed, true);
            app.FixedFrameButton.Text = 'Fixed Frame';
            app.RMtable = uitable(app.RotationaMatrix);
            app.RMtable.ColumnName = {'Rotation Matrix'};
            app.RMtable.RowName = {};
            app.RMtable.DisplayDataChangedFcn = createCallbackFcn(app, @CurrentFrameButtonPushed, true);
            app.AATable = uitable(app.RotationaMatrix);
            app.AATable.ColumnName = {'Axis'; 'Angle'};
            app.AATable.RowName = {};
            app.AngleEditFieldLabel = uilabel(app.RotationaMatrix);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.RotationaMatrix, 'numeric');
            app.AxisEditFieldLabel = uilabel(app.RotationaMatrix);
            app.AxisEditFieldLabel.Text = 'Axis';
            app.AxisEditField = uieditfield(app.RotationaMatrix, 'text');
            app.RotationNumberEditFieldLabel = uilabel(app.RotationaMatrix);
            app.RotationNumberEditFieldLabel.Text = 'Rotation Number';
            app.RotationNumberEditField = uieditfield(app.RotationaMatrix, 'numeric');
            app.UpdateButton = uibutton(app.RotationaMatrix, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.RotationaMatrix.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rotation_matrix_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RotationaMatrix)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RotationaMatrix)
        end
    end
end