classdef fdyn < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DynamicsUIFigure         matlab.ui.Figure
        GravityDefinationPanel   matlab.ui.container.Panel
        TextArea                 matlab.ui.control.TextArea
        gxEditFieldLabel         matlab.ui.control.Label
        gx                       matlab.ui.control.NumericEditField
        gyEditFieldLabel         matlab.ui.control.Label
        gy                       matlab.ui.control.NumericEditField
        gzEditFieldLabel         matlab.ui.control.Label
        gz                       matlab.ui.control.NumericEditField
        define_gravity           matlab.ui.control.Button
        Panel2                   matlab.ui.container.Panel
        LinkEditFieldLabel       matlab.ui.control.Label
        link_no                  matlab.ui.control.NumericEditField
        LinkmassEditFieldLabel   matlab.ui.control.Label
        link_mass                matlab.ui.control.NumericEditField
        CGxEditFieldLabel        matlab.ui.control.Label
        cgx                      matlab.ui.control.NumericEditField
        CGyEditFieldLabel        matlab.ui.control.Label
        cgy                      matlab.ui.control.NumericEditField
        CGzEditFieldLabel        matlab.ui.control.Label
        cgz                      matlab.ui.control.NumericEditField
        IxxEditFieldLabel        matlab.ui.control.Label
        Ixx                      matlab.ui.control.NumericEditField
        IxyEditFieldLabel        matlab.ui.control.Label
        Ixy                      matlab.ui.control.NumericEditField
        IxzEditFieldLabel        matlab.ui.control.Label
        Ixz                      matlab.ui.control.NumericEditField
        IyyEditFieldLabel        matlab.ui.control.Label
        Iyy                      matlab.ui.control.NumericEditField
        IyzEditField_2Label      matlab.ui.control.Label
        Iyz                      matlab.ui.control.NumericEditField
        IzzEditFieldLabel        matlab.ui.control.Label
        Izz                      matlab.ui.control.NumericEditField
        JmEditFieldLabel         matlab.ui.control.Label
        Jm                       matlab.ui.control.NumericEditField
        GearRatioEditFieldLabel  matlab.ui.control.Label
        G                        matlab.ui.control.NumericEditField
        update_dynamic_params    matlab.ui.control.Button
        DynamicDefinationsPanel  matlab.ui.container.Panel
        DoyouwanttoseethemanipulatordynamicsequationsyornLabel  matlab.ui.control.Label
        eqn_or_not               matlab.ui.control.EditField
        OkButton                 matlab.ui.control.Button
        InputthetorqueofthatjointasarowvectorfunctionoftimeLabel  matlab.ui.control.Label
        torque_input             matlab.ui.control.EditField
        torque_update            matlab.ui.control.Button
        EndTimeEditFieldLabel    matlab.ui.control.Label
        tf                       matlab.ui.control.NumericEditField
        run_simulation           matlab.ui.control.Button
        InitialjointpositionsEditFieldLabel  matlab.ui.control.Label
        q0                       matlab.ui.control.EditField
        InitialJointDerivativesEditFieldLabel  matlab.ui.control.Label
        qd0                      matlab.ui.control.EditField
        TextArea_3               matlab.ui.control.TextArea
        B_Table                  matlab.ui.control.Table
        RobotdefinationPanel     matlab.ui.container.Panel
        TextArea_2               matlab.ui.control.TextArea
        NumberofLinksLabel       matlab.ui.control.Label
        n_joints                 matlab.ui.control.NumericEditField
        TypeOfJointButtonGroup   matlab.ui.container.ButtonGroup
        is_prismatic             matlab.ui.control.RadioButton
        is_revolute              matlab.ui.control.RadioButton
        LinkLabel                matlab.ui.control.Label
        link_number              matlab.ui.control.NumericEditField
        dDistancealongzaxisEditFieldLabel  matlab.ui.control.Label
        d                        matlab.ui.control.NumericEditField
        aDistancealongxaxisEditFieldLabel  matlab.ui.control.Label
        a                        matlab.ui.control.NumericEditField
        alphaAngleaboutxaxisinDEGREESEditFieldLabel  matlab.ui.control.Label
        alpha                    matlab.ui.control.NumericEditField
        thetaangleaboutzaxisinDEGREESEditFieldLabel  matlab.ui.control.Label
        theta                    matlab.ui.control.NumericEditField
        UpdateForThisLinkButton  matlab.ui.control.Button
        C_Table                  matlab.ui.control.Table
        G_Table                  matlab.ui.control.Table
        joint_angles             matlab.ui.control.UIAxes
        joint_derivatives        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        g=[] % Gravity Vector


        R % Robot
        Links={} %Array of Links
        torqfun=[] % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run startup_rvc.m
        end

        % Button pushed function: define_gravity
        function define_gravityPushed(app, event)
            %This operation defines robot gravity
            app.g=[app.gx.Value, app.gy.Value app.gz.Value];
            
            app.R.gravity=app.g;
            
            
            %app.B_Table.DisplayData()
        end

        % Button pushed function: UpdateForThisLinkButton
        function UpdateForThisLinkButtonPushed(app, event)
           %This function defines robot links
           
           n=app.n_joints.Value;
           i=app.link_number.Value;
           
           %keyboard();
           
           if(app.is_prismatic.Value)
                app.Links{i}=Link('a',app.a.Value,'alpha',app.alpha.Value*pi/180,'theta',app.theta.Value*pi/180);
           else
               app.Links{i}=Link('a',app.a.Value,'alpha',app.alpha.Value*pi/180,'d',app.d.Value);
           end
           
           
           if(i==n)
               for j=1:n
                   x(j)=app.Links{j};
               end
               app.R=SerialLink(x);
           end
           
        end

        % Button pushed function: update_dynamic_params
        function update_dynamic_paramsButtonPushed(app, event)
            %This updates the link mass and inertia
            i=app.link_no.Value;
            
            app.R.links(i).m=app.link_mass.Value;
            app.R.links(i).r=[app.cgx.Value app.cgy.Value app.cgz.Value];
            
            app.R.links(i).I=[app.Ixx.Value -app.Ixy.Value -app.Ixz.Value; -app.Ixy.Value app.Iyy.Value -app.Ixz.Value; -app.Ixy.Value -app.Iyz.Value app.Izz.Value];
            
            app.R.links(i).Jm=app.Jm.Value;
            app.R.links(i).G=app.G.Value;


        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            %This displays if you need the symbolic equations
            if(app.eqn_or_not.Value=='y')
                n=app.R.n; %Number of joints\
                
                %keyboard();
                
                syms q [1 n]
                syms qd [1 n]
                
                app.R.fast=0;
                
                %keyboard();
                B=app.R.inertia(q);
                C=app.R.coriolis(q,qd);
                G=app.R.gravjac(q);
                
                app.B_Table.Data=string(B)
                
                app.C_Table.Data=string(C)
                app.G_Table.Data=string(G)
                
            end
            
            
        end

        % Button pushed function: torque_update
        function torque_updatePushed(app, event)
            %Updates the torque
            
            temp=app.torque_input.Value;
            
            temp=inline(temp,'t');
            app.torqfun=@(R,t,q,qd) temp(t);


        end

        % Button pushed function: run_simulation
        function run_simulationPushed(app, event)
            %keyboard();
            
%             t0=app.t0.Value;
            tf=app.tf.Value;
            
            q0=str2num(app.q0.Value);
            qd0=str2num(app.qd0.Value);
            
            [t,q,qd]=app.R.fdyn(tf,app.torqfun,q0,qd0);
            
            n=app.R.n;
            
            title(app.joint_angles,'Joint Positions')
            title(app.joint_derivatives,'Joint Derivatives')
            hold(app.joint_angles);
            hold(app.joint_derivatives);
            for j=1:n
                plot(app.joint_angles,t,q(:,j))
                plot(app.joint_derivatives,t,qd(:,j));
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DynamicsUIFigure = uifigure('Visible', 'off');
            app.DynamicsUIFigure.Name = 'Dynamics';
            app.GravityDefinationPanel = uipanel(app.DynamicsUIFigure);
            app.GravityDefinationPanel.Title = 'Gravity Defination';
            app.TextArea = uitextarea(app.GravityDefinationPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Enter the gravity vector w r t the robot frame 0'};
            app.gxEditFieldLabel = uilabel(app.GravityDefinationPanel);
            app.gxEditFieldLabel.Text = {'gx'; ''};
            app.gx = uieditfield(app.GravityDefinationPanel, 'numeric');
            app.gyEditFieldLabel = uilabel(app.GravityDefinationPanel);
            app.gyEditFieldLabel.Text = 'gy';
            app.gy = uieditfield(app.GravityDefinationPanel, 'numeric');
            app.gy.Value = 9.81;
            app.gzEditFieldLabel = uilabel(app.GravityDefinationPanel);
            app.gzEditFieldLabel.Text = 'gz';
            app.gz = uieditfield(app.GravityDefinationPanel, 'numeric');
            app.define_gravity = uibutton(app.GravityDefinationPanel, 'push');
            app.define_gravity.ButtonPushedFcn = createCallbackFcn(app, @define_gravityPushed, true);
            app.define_gravity.Text = 'Update';
            app.Panel2 = uipanel(app.DynamicsUIFigure);
            app.Panel2.Title = 'Panel2';
            app.LinkEditFieldLabel = uilabel(app.Panel2);
            app.LinkEditFieldLabel.Text = '# Link';
            app.link_no = uieditfield(app.Panel2, 'numeric');
            app.link_no.Value = 3;
            app.LinkmassEditFieldLabel = uilabel(app.Panel2);
            app.LinkmassEditFieldLabel.Text = 'Link mass';
            app.link_mass = uieditfield(app.Panel2, 'numeric');
            app.link_mass.Value = 3;
            app.CGxEditFieldLabel = uilabel(app.Panel2);
            app.CGxEditFieldLabel.Text = 'CG x';
            app.cgx = uieditfield(app.Panel2, 'numeric');
            app.cgx.Value = 1;
            app.CGyEditFieldLabel = uilabel(app.Panel2);
            app.CGyEditFieldLabel.Text = 'CG y';
            app.cgy = uieditfield(app.Panel2, 'numeric');
            app.CGzEditFieldLabel = uilabel(app.Panel2);
            app.CGzEditFieldLabel.Text = 'CG z';
            app.cgz = uieditfield(app.Panel2, 'numeric');
            app.IxxEditFieldLabel = uilabel(app.Panel2);
            app.IxxEditFieldLabel.Text = 'Ixx';
            app.Ixx = uieditfield(app.Panel2, 'numeric');
            app.Ixx.Value = 1;
            app.IxyEditFieldLabel = uilabel(app.Panel2);
            app.IxyEditFieldLabel.Text = 'Ixy';
            app.Ixy = uieditfield(app.Panel2, 'numeric');
            app.IxzEditFieldLabel = uilabel(app.Panel2);
            app.IxzEditFieldLabel.Text = 'Ixz';
            app.Ixz = uieditfield(app.Panel2, 'numeric');
            app.IyyEditFieldLabel = uilabel(app.Panel2);
            app.IyyEditFieldLabel.Text = 'Iyy';
            app.Iyy = uieditfield(app.Panel2, 'numeric');
            app.Iyy.Value = 1;
            app.IyzEditField_2Label = uilabel(app.Panel2);
            app.IyzEditField_2Label.Text = 'Iyz';
            app.Iyz = uieditfield(app.Panel2, 'numeric');
            app.IzzEditFieldLabel = uilabel(app.Panel2);
            app.IzzEditFieldLabel.Text = 'Izz';
            app.Izz = uieditfield(app.Panel2, 'numeric');
            app.Izz.Value = 1;
            app.JmEditFieldLabel = uilabel(app.Panel2);
            app.JmEditFieldLabel.Text = 'Jm';
            app.Jm = uieditfield(app.Panel2, 'numeric');
            app.Jm.Value = 11;
            app.GearRatioEditFieldLabel = uilabel(app.Panel2);
            app.GearRatioEditFieldLabel.Text = 'Gear Ratio';
            app.G = uieditfield(app.Panel2, 'numeric');
            app.G.Value = 111;
            app.update_dynamic_params = uibutton(app.Panel2, 'push');
            app.update_dynamic_params.ButtonPushedFcn = createCallbackFcn(app, @update_dynamic_paramsButtonPushed, true);
            app.update_dynamic_params.Text = 'Update';
            app.DynamicDefinationsPanel = uipanel(app.DynamicsUIFigure);
            app.DynamicDefinationsPanel.Title = 'Dynamic Definations';
            app.DoyouwanttoseethemanipulatordynamicsequationsyornLabel = uilabel(app.DynamicDefinationsPanel);
            app.DoyouwanttoseethemanipulatordynamicsequationsyornLabel.Text = 'Do you want to see the manipulator dynamics equations (y or n)?';
            app.eqn_or_not = uieditfield(app.DynamicDefinationsPanel, 'text');
            app.eqn_or_not.Value = 'y';
            app.OkButton = uibutton(app.DynamicDefinationsPanel, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.InputthetorqueofthatjointasarowvectorfunctionoftimeLabel = uilabel(app.DynamicDefinationsPanel);
            app.InputthetorqueofthatjointasarowvectorfunctionoftimeLabel.Text = 'Input the torque of that joint as a row vector function of time ';
            app.torque_input = uieditfield(app.DynamicDefinationsPanel, 'text');
            app.torque_update = uibutton(app.DynamicDefinationsPanel, 'push');
            app.torque_update.ButtonPushedFcn = createCallbackFcn(app, @torque_updatePushed, true);
            app.torque_update.Text = 'Update';
            app.EndTimeEditFieldLabel = uilabel(app.DynamicDefinationsPanel);
            app.EndTimeEditFieldLabel.Text = 'End Time';
            app.tf = uieditfield(app.DynamicDefinationsPanel, 'numeric');
            app.run_simulation = uibutton(app.DynamicDefinationsPanel, 'push');
            app.run_simulation.ButtonPushedFcn = createCallbackFcn(app, @run_simulationPushed, true);
            app.run_simulation.Text = 'Run Simulation';
            app.InitialjointpositionsEditFieldLabel = uilabel(app.DynamicDefinationsPanel);
            app.InitialjointpositionsEditFieldLabel.Text = 'Initial joint positions';
            app.q0 = uieditfield(app.DynamicDefinationsPanel, 'text');
            app.InitialJointDerivativesEditFieldLabel = uilabel(app.DynamicDefinationsPanel);
            app.InitialJointDerivativesEditFieldLabel.Text = 'Initial Joint Derivatives';
            app.qd0 = uieditfield(app.DynamicDefinationsPanel, 'text');
            app.TextArea_3 = uitextarea(app.DynamicDefinationsPanel);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'Input the joint angles and derivatives like this: [q1 q2]. Input torques like [t1 t2]'};
            app.B_Table = uitable(app.DynamicsUIFigure);
            app.B_Table.ColumnName = {'B'};
            app.B_Table.RowName = {};
            app.RobotdefinationPanel = uipanel(app.DynamicsUIFigure);
            app.RobotdefinationPanel.Title = 'Robot defination';
            app.TextArea_2 = uitextarea(app.RobotdefinationPanel);
            app.TextArea_2.Value = {'Define the Robot here'};
            app.NumberofLinksLabel = uilabel(app.RobotdefinationPanel);
            app.NumberofLinksLabel.Text = 'Number of Links';
            app.n_joints = uieditfield(app.RobotdefinationPanel, 'numeric');
            app.n_joints.Value = 3;
            app.TypeOfJointButtonGroup = uibuttongroup(app.RobotdefinationPanel);
            app.TypeOfJointButtonGroup.Title = 'Type Of Joint';
            app.is_prismatic = uiradiobutton(app.TypeOfJointButtonGroup);
            app.is_prismatic.Text = 'Prismatic';
            app.is_prismatic.Value = true;
            app.is_revolute = uiradiobutton(app.TypeOfJointButtonGroup);
            app.is_revolute.Text = 'Revolute';
            app.LinkLabel = uilabel(app.RobotdefinationPanel);
            app.LinkLabel.Text = '# Link';
            app.link_number = uieditfield(app.RobotdefinationPanel, 'numeric');
            app.link_number.Value = 3;
            app.dDistancealongzaxisEditFieldLabel = uilabel(app.RobotdefinationPanel);
            app.dDistancealongzaxisEditFieldLabel.Text = 'd (Distance along z axis)';
            app.d = uieditfield(app.RobotdefinationPanel, 'numeric');
            app.aDistancealongxaxisEditFieldLabel = uilabel(app.RobotdefinationPanel);
            app.aDistancealongxaxisEditFieldLabel.Text = 'a (Distance along x axis)';
            app.a = uieditfield(app.RobotdefinationPanel, 'numeric');
            app.a.Value = 1;
            app.alphaAngleaboutxaxisinDEGREESEditFieldLabel = uilabel(app.RobotdefinationPanel);
            app.alphaAngleaboutxaxisinDEGREESEditFieldLabel.Text = 'alpha (Angle about x axis) in DEGREES';
            app.alpha = uieditfield(app.RobotdefinationPanel, 'numeric');
            app.thetaangleaboutzaxisinDEGREESEditFieldLabel = uilabel(app.RobotdefinationPanel);
            app.thetaangleaboutzaxisinDEGREESEditFieldLabel.Text = 'theta (angle about z axis) in DEGREES';
            app.theta = uieditfield(app.RobotdefinationPanel, 'numeric');
            app.UpdateForThisLinkButton = uibutton(app.RobotdefinationPanel, 'push');
            app.UpdateForThisLinkButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateForThisLinkButtonPushed, true);
            app.UpdateForThisLinkButton.Text = 'Update For This Link';
            app.C_Table = uitable(app.DynamicsUIFigure);
            app.C_Table.ColumnName = {'C'};
            app.C_Table.RowName = {};
            app.G_Table = uitable(app.DynamicsUIFigure);
            app.G_Table.ColumnName = {'G'};
            app.G_Table.RowName = {};
            app.joint_angles = uiaxes(app.DynamicsUIFigure);
            title(app.joint_angles, 'Title')
            xlabel(app.joint_angles, 'X')
            ylabel(app.joint_angles, 'Y')
            app.joint_derivatives = uiaxes(app.DynamicsUIFigure);
            title(app.joint_derivatives, 'Title')
            xlabel(app.joint_derivatives, 'X')
            ylabel(app.joint_derivatives, 'Y')
            % Show the figure after all components are created
            app.DynamicsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fdyn

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DynamicsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DynamicsUIFigure)
        end
    end
end