classdef Inverse_Kinematics_Jac_noDH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        INVERSE_KINEMATICS_JACOBIAN_NoDH  matlab.ui.Figure
        INVERSEKINEMATICS            matlab.ui.control.Label
        Qdot                         matlab.ui.control.Table
        DHTable                      matlab.ui.control.Table
        AnglesmustbeenteredinDEGREEUnitONLYLabel  matlab.ui.control.Label
        LinkDefinations              matlab.ui.container.Panel
        LinkNo                       matlab.ui.control.NumericEditField
        LinkNoLabel                  matlab.ui.control.Label
        NumberofLinksEditFieldLabel  matlab.ui.control.Label
        TotalLinks                   matlab.ui.control.NumericEditField
        aInput                       matlab.ui.control.NumericEditField
        LengthoflinkLabel            matlab.ui.control.Label
        AnglebetweentheZaxisoftheconsecutivelinksLabel  matlab.ui.control.Label
        alphaInput                   matlab.ui.control.NumericEditField
        AnglebetweentheXaxisoftheconsecutivelinksLabel  matlab.ui.control.Label
        thetaInput                   matlab.ui.control.NumericEditField
        dInput                       matlab.ui.control.NumericEditField
        DistancebetweenjointsinZaxisofpreviousjointLabel  matlab.ui.control.Label
        PrismaticJointLimitsLabel    matlab.ui.control.Label
        Plimmin                      matlab.ui.control.NumericEditField
        Plimmax                      matlab.ui.control.NumericEditField
        MinLabel                     matlab.ui.control.Label
        MaxLabel                     matlab.ui.control.Label
        DHUpdate                     matlab.ui.control.Button
        Linktype                     matlab.ui.control.EditField
        TextArea_2                   matlab.ui.control.TextArea
        EndPoseValues                matlab.ui.container.Panel
        POSITIONFUNCTIONLabel        matlab.ui.control.Label
        position_function            matlab.ui.control.EditField
        ANGLEFUNCTIONLabel           matlab.ui.control.Label
        angle_function               matlab.ui.control.EditField
        TIMEEditFieldLabel           matlab.ui.control.Label
        Time                         matlab.ui.control.NumericEditField
        Eulertype                    matlab.ui.control.DropDown
        EndposedefinedinwhicheulerangleLabel  matlab.ui.control.Label
        TextArea_5                   matlab.ui.control.TextArea
        InitialVariablesPanel        matlab.ui.container.Panel
        JointNoEditFieldLabel        matlab.ui.control.Label
        JointNo                      matlab.ui.control.NumericEditField
        ValueEditFieldLabel          matlab.ui.control.Label
        JointVariable                matlab.ui.control.NumericEditField
        TextArea_4                   matlab.ui.control.TextArea
        RUNButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        theta = [];
        a   =   [];
        d   =   [];
        alpha = [];
        INVS =  [];
        min  =  [];
        max  =  [];
        type =  [];
        jvalue = [];

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run startup_rvc.m
        end

        % Button pushed function: DHUpdate
        function DHUpdateButtonPushed(app, event)
            n = app.TotalLinks.Value;
            i = app.LinkNo.Value;
            app.theta(i) = app.thetaInput.Value;
            app.d(i) = app.dInput.Value;
            app.a(i) = app.aInput.Value;
            app.alpha(i) = app.alphaInput.Value;
            app.min(i) = app.Plimmin.Value; 
            app.max(i) = app.Plimmax.Value;
            app.type(i) = app.Linktype.Value;
            temp = (app.type);
            if i==n
                for i = 1:n
                    DH(i,:) = [(pi/180)*app.theta(i), app.d(i), app.a(i), (pi/180)*app.alpha(i)];
                end
                app.DHTable.Data = DH;
            end
               
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
             i =app.JointNo.Value;
            n =  app.TotalLinks.Value;
            DH = app.DHTable.Data;
            low = app.min;
            high = app.max;
            temp = round(app.type)
            ET = app.Eulertype.Value
            T = app.Time.Value;
        
            app.jvalue(i) = app.JointVariable.Value;
            
            pd=inline(app.position_function.Value,'t')
            
            phid=inline(app.angle_function.Value,'t')

            ff =@(t)[pd(t)';phid(t)'];
            
            if i == n
            q0 = app.jvalue;
            [Q] = Invkinejacob(n, temp, DH, low, high, ff,ET,T, q0);
           app.Qdot.Data = Q;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.INVERSE_KINEMATICS_JACOBIAN_NoDH = uifigure('Visible', 'off');
            app.INVERSE_KINEMATICS_JACOBIAN_NoDH.Name = 'INVERSE KINEMATICS USING JACOBIANS No DH';
            app.INVERSEKINEMATICS = uilabel(app.INVERSE_KINEMATICS_JACOBIAN_NoDH);
            app.INVERSEKINEMATICS.Text = 'INVERSE KINEMATICS USING JACOBIANS';
            app.Qdot = uitable(app.INVERSE_KINEMATICS_JACOBIAN_NoDH);
            app.Qdot.ColumnName = {'VALUE OF JOINT VARIABLES IN ORDER OF 1 TO N'};
            app.Qdot.RowName = {};
            app.DHTable = uitable(app.INVERSE_KINEMATICS_JACOBIAN_NoDH);
            app.DHTable.ColumnName = {'Theta'; 'd'; 'a'; 'alpha'};
            app.DHTable.RowName = {''};
            app.AnglesmustbeenteredinDEGREEUnitONLYLabel = uilabel(app.INVERSE_KINEMATICS_JACOBIAN_NoDH);
            app.AnglesmustbeenteredinDEGREEUnitONLYLabel.Text = ' Angles must be entered in DEGREE Unit ONLY';
            app.LinkDefinations = uipanel(app.INVERSE_KINEMATICS_JACOBIAN_NoDH);
            app.LinkDefinations.Title = 'Enter Link Definations one by one';
            app.LinkNo = uieditfield(app.LinkDefinations, 'numeric');
            app.LinkNoLabel = uilabel(app.LinkDefinations);
            app.LinkNoLabel.Text = 'Link No.';
            app.NumberofLinksEditFieldLabel = uilabel(app.LinkDefinations);
            app.NumberofLinksEditFieldLabel.Text = 'Number of Links';
            app.TotalLinks = uieditfield(app.LinkDefinations, 'numeric');
            app.aInput = uieditfield(app.LinkDefinations, 'numeric');
            app.LengthoflinkLabel = uilabel(app.LinkDefinations);
            app.LengthoflinkLabel.Text = 'Length of link';
            app.AnglebetweentheZaxisoftheconsecutivelinksLabel = uilabel(app.LinkDefinations);
            app.AnglebetweentheZaxisoftheconsecutivelinksLabel.Text = 'Angle between the Z axis of the consecutive links';
            app.alphaInput = uieditfield(app.LinkDefinations, 'numeric');
            app.AnglebetweentheXaxisoftheconsecutivelinksLabel = uilabel(app.LinkDefinations);
            app.AnglebetweentheXaxisoftheconsecutivelinksLabel.Text = 'Angle between the X axis of the consecutive links';
            app.thetaInput = uieditfield(app.LinkDefinations, 'numeric');
            app.dInput = uieditfield(app.LinkDefinations, 'numeric');
            app.DistancebetweenjointsinZaxisofpreviousjointLabel = uilabel(app.LinkDefinations);
            app.DistancebetweenjointsinZaxisofpreviousjointLabel.Text = 'Distance between joints in Z axis of previous joint';
            app.PrismaticJointLimitsLabel = uilabel(app.LinkDefinations);
            app.PrismaticJointLimitsLabel.Text = 'Prismatic Joint Limits';
            app.Plimmin = uieditfield(app.LinkDefinations, 'numeric');
            app.Plimmax = uieditfield(app.LinkDefinations, 'numeric');
            app.MinLabel = uilabel(app.LinkDefinations);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.LinkDefinations);
            app.MaxLabel.Text = 'Max';
            app.DHUpdate = uibutton(app.LinkDefinations, 'push');
            app.DHUpdate.ButtonPushedFcn = createCallbackFcn(app, @DHUpdateButtonPushed, true);
            app.DHUpdate.Text = 'UPDATE';
            app.Linktype = uieditfield(app.LinkDefinations, 'text');
            app.TextArea_2 = uitextarea(app.LinkDefinations);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Enter r for revolute and p for prismatic'};
            app.EndPoseValues = uipanel(app.INVERSE_KINEMATICS_JACOBIAN_NoDH);
            app.EndPoseValues.Title = 'Values of Robot''s End Pose';
            app.POSITIONFUNCTIONLabel = uilabel(app.EndPoseValues);
            app.POSITIONFUNCTIONLabel.Text = 'POSITION FUNCTION';
            app.position_function = uieditfield(app.EndPoseValues, 'text');
            app.ANGLEFUNCTIONLabel = uilabel(app.EndPoseValues);
            app.ANGLEFUNCTIONLabel.Text = 'ANGLE FUNCTION';
            app.angle_function = uieditfield(app.EndPoseValues, 'text');
            app.TIMEEditFieldLabel = uilabel(app.EndPoseValues);
            app.TIMEEditFieldLabel.Text = 'TIME';
            app.Time = uieditfield(app.EndPoseValues, 'numeric');
            app.Eulertype = uidropdown(app.EndPoseValues);
            app.Eulertype.Items = {'ZYZ', 'RPY'};
            app.Eulertype.Value = 'ZYZ';
            app.EndposedefinedinwhicheulerangleLabel = uilabel(app.EndPoseValues);
            app.EndposedefinedinwhicheulerangleLabel.Text = 'End pose defined in which euler angle';
            app.TextArea_5 = uitextarea(app.EndPoseValues);
            app.TextArea_5.Value = {'Enter the 3 position functions and 3 angles functions for the desired end effector pose, enter those function as row vectors and enter them as function of time t only and in ROW VECTOR FORMAT.'; 'eg [sin(t) cos(t) t]'};
            app.InitialVariablesPanel = uipanel(app.INVERSE_KINEMATICS_JACOBIAN_NoDH);
            app.InitialVariablesPanel.Title = 'Initial Variables';
            app.JointNoEditFieldLabel = uilabel(app.InitialVariablesPanel);
            app.JointNoEditFieldLabel.Text = 'Joint No.';
            app.JointNo = uieditfield(app.InitialVariablesPanel, 'numeric');
            app.ValueEditFieldLabel = uilabel(app.InitialVariablesPanel);
            app.ValueEditFieldLabel.Text = 'Value';
            app.JointVariable = uieditfield(app.InitialVariablesPanel, 'numeric');
            app.TextArea_4 = uitextarea(app.InitialVariablesPanel);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Enter Value for Revolute in RADIANS and Prismatic in m.'; 'after entering each of the above values press the below button'; 'Value of variables to left should be entered only once'};
            app.RUNButton = uibutton(app.InitialVariablesPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            % Show the figure after all components are created
            app.INVERSE_KINEMATICS_JACOBIAN_NoDH.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Inverse_Kinematics_Jac_noDH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.INVERSE_KINEMATICS_JACOBIAN_NoDH)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.INVERSE_KINEMATICS_JACOBIAN_NoDH)
        end
    end
end