classdef Inverse_Kinematics_noDH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InverseKinematicsNoDHUIFigure  matlab.ui.Figure
        DHTable                        matlab.ui.control.Table
        INVERSEKINEMATICS              matlab.ui.control.Label
        EndPoseValues                  matlab.ui.container.Panel
        EulerAnglesDropDownLabel       matlab.ui.control.Label
        EulerAngle                     matlab.ui.control.DropDown
        Endframeangledefination        matlab.ui.control.Label
        PHIEditFieldLabel              matlab.ui.control.Label
        PHI                            matlab.ui.control.NumericEditField
        THETAEditFieldLabel            matlab.ui.control.Label
        THETA                          matlab.ui.control.NumericEditField
        PSIEditFieldLabel              matlab.ui.control.Label
        PSI                            matlab.ui.control.NumericEditField
        PositioninXEditFieldLabel      matlab.ui.control.Label
        PX                             matlab.ui.control.NumericEditField
        PositioninYEditFieldLabel      matlab.ui.control.Label
        PY                             matlab.ui.control.NumericEditField
        PositioninZEditFieldLabel      matlab.ui.control.Label
        PZ                             matlab.ui.control.NumericEditField
        RUNINVERSEButton               matlab.ui.control.Button
        Inverse                        matlab.ui.control.Table
        RESETButton                    matlab.ui.control.Button
        AllValuesforAnglesmustbeenteredinDEGREEUnitONLYLabel  matlab.ui.control.Label
        LinkDefinations                matlab.ui.container.Panel
        LinkNo                         matlab.ui.control.NumericEditField
        LinkNoLabel                    matlab.ui.control.Label
        NumberofLinksEditFieldLabel    matlab.ui.control.Label
        TotalLinks                     matlab.ui.control.NumericEditField
        aInput                         matlab.ui.control.NumericEditField
        LengthoflinkLabel              matlab.ui.control.Label
        AnglebetweentheZaxisoftheconsecutivelinksLabel  matlab.ui.control.Label
        alphaInput                     matlab.ui.control.NumericEditField
        AnglebetweentheXaxisoftheconsecutivelinksLabel  matlab.ui.control.Label
        thetaInput                     matlab.ui.control.NumericEditField
        dInput                         matlab.ui.control.NumericEditField
        DistancebetweenjointsinZaxisofpreviousjointLabel  matlab.ui.control.Label
        PrismaticJointLimitsLabel      matlab.ui.control.Label
        Plimmin                        matlab.ui.control.NumericEditField
        Plimmax                        matlab.ui.control.NumericEditField
        MinLabel                       matlab.ui.control.Label
        MaxLabel                       matlab.ui.control.Label
        DHUpdate                       matlab.ui.control.Button
        Linktype                       matlab.ui.control.EditField
        TextArea                       matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        theta = [];
        a   =   [];
        d   =   [];
        alpha = [];
        INVS =  [];
        q    =  [];
        min  =  [];
        max  =  [];
        type =  [];
        m    =  [];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run startup_rvc.m
        end

        % Callback function: DHTable, DHUpdate
        function DHUpdateButtonPushed(app, event)
          n = app.TotalLinks.Value;
            i = app.LinkNo.Value;
            app.theta(i) = app.thetaInput.Value;
            app.d(i) = app.dInput.Value;
            app.a(i) = app.aInput.Value;
            app.alpha(i) = app.alphaInput.Value;
            app.min(i) = app.Plimmin.Value; 
            app.max(i) = app.Plimmax.Value;
            app.type(i) = app.Linktype.Value;
            temp = (app.type);
            if i==n
                for i = 1:n
                    DH(i,:) = [(pi/180)*app.theta(i), app.d(i), app.a(i), (pi/180)*app.alpha(i)];
                end
                app.DHTable.Data = DH;
            end
%                 if app.LinkType.Value == 'Revolute'
%                      app.L(i,:) = Link('d',app.d(i), 'a', app.a(i), 'alpha',(pi/180)*app.alpha(i))
%                 else
%                      app.L(i,:) = Link('theta',(pi/180)*app.theta(i), 'a', app.a(i), 'alpha',(pi/180)*app.alpha(i))
%                      app.L(i,:).qlim = [app.min(i) app.max(i)];
%                 end
        end

        % Button pushed function: RUNINVERSEButton
        function RUNINVERSEButtonPushed(app, event)
            
            n =  app.TotalLinks.Value;
            DH = app.DHTable.Data;
            low = app.min;
            high = app.max;
            temp = round(app.type)
           for k = 1:n
                if temp(:,k) == 114
                     L{k} = Link('d',DH(k,2), 'a', DH(k,3), 'alpha', DH(k,4));
                else 
                    L{k} = Link('theta',DH(k,1), 'a', DH(k,3), 'alpha', DH(k,4)) 
                     L{k}.qlim = [low(k) high(k)];
                end
            end
            for b = 1:n
                X(b,:) = L{b};
            end
       
            R = SerialLink(X)
           
            Px = app.PX.Value;
            Py = app.PY.Value;
            Pz = app.PZ.Value;
            Phi   = app.PHI.Value;
            Theta = app.THETA.Value;
            Psi   = app.PSI.Value;
            
            Eul = app.EulerAngle.Value
            
            if app.EulerAngle.Value == 'ZYZ'
                Rot = eul2r(Phi,Theta,Psi,'deg')  
            else 
                Rot = rotz(Phi*pi/180)*roty(Theta*pi/180)*rotx(Psi*pi/180)
            end
            Trans = [ Rot;0 0 0];
            Trans = [Trans,[Px;Py;Pz;1]];
            
            app.INVS = R.ikunc(Trans) ;
            for l = 1:n
                app.q(l) = app.INVS(:,l);
            end
             app.Inverse.Data = (app.q)';
        
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.aInput.Value     = 0;
            app.dInput.Value     = 0;
            app.thetaInput.Value = 0;
            app.alphaInput.Value = 0;
            app.LinkNo.Value     = 0;
            app.TotalLinks.Value = 0;
            app.PX.Value         = 0;
            app.PY.Value         = 0;
            app.PZ.Value         = 0;
            app.PHI.Value        = 0;
            app.THETA.Value      = 0;
            app.PSI.Value        = 0;
            app.DHTable.Data     = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InverseKinematicsNoDHUIFigure = uifigure('Visible', 'off');
            app.InverseKinematicsNoDHUIFigure.Name = 'Inverse Kinematics No DH';
            app.DHTable = uitable(app.InverseKinematicsNoDHUIFigure);
            app.DHTable.ColumnName = {'Theta'; 'd'; 'a'; 'alpha'};
            app.DHTable.RowName = {''};
            app.DHTable.DisplayDataChangedFcn = createCallbackFcn(app, @DHUpdateButtonPushed, true);
            app.INVERSEKINEMATICS = uilabel(app.InverseKinematicsNoDHUIFigure);
            app.INVERSEKINEMATICS.Text = 'INVERSE KINEMATICS';
            app.EndPoseValues = uipanel(app.InverseKinematicsNoDHUIFigure);
            app.EndPoseValues.Title = 'Values of Robot''s End Pose';
            app.EulerAnglesDropDownLabel = uilabel(app.EndPoseValues);
            app.EulerAnglesDropDownLabel.Text = 'Euler Angles';
            app.EulerAngle = uidropdown(app.EndPoseValues);
            app.EulerAngle.Items = {'ZYZ', 'ZYX'};
            app.EulerAngle.Value = 'ZYZ';
            app.Endframeangledefination = uilabel(app.EndPoseValues);
            app.Endframeangledefination.Text = 'End frame angle defination';
            app.PHIEditFieldLabel = uilabel(app.EndPoseValues);
            app.PHIEditFieldLabel.Text = 'PHI';
            app.PHI = uieditfield(app.EndPoseValues, 'numeric');
            app.THETAEditFieldLabel = uilabel(app.EndPoseValues);
            app.THETAEditFieldLabel.Text = 'THETA';
            app.THETA = uieditfield(app.EndPoseValues, 'numeric');
            app.PSIEditFieldLabel = uilabel(app.EndPoseValues);
            app.PSIEditFieldLabel.Text = 'PSI';
            app.PSI = uieditfield(app.EndPoseValues, 'numeric');
            app.PositioninXEditFieldLabel = uilabel(app.EndPoseValues);
            app.PositioninXEditFieldLabel.Text = 'Position in X';
            app.PX = uieditfield(app.EndPoseValues, 'numeric');
            app.PositioninYEditFieldLabel = uilabel(app.EndPoseValues);
            app.PositioninYEditFieldLabel.Text = 'Position in Y';
            app.PY = uieditfield(app.EndPoseValues, 'numeric');
            app.PositioninZEditFieldLabel = uilabel(app.EndPoseValues);
            app.PositioninZEditFieldLabel.Text = 'Position in Z';
            app.PZ = uieditfield(app.EndPoseValues, 'numeric');
            app.RUNINVERSEButton = uibutton(app.EndPoseValues, 'push');
            app.RUNINVERSEButton.ButtonPushedFcn = createCallbackFcn(app, @RUNINVERSEButtonPushed, true);
            app.RUNINVERSEButton.Text = 'RUN INVERSE';
            app.Inverse = uitable(app.InverseKinematicsNoDHUIFigure);
            app.Inverse.ColumnName = {'Value of Joint Variables in Order 1 to n'};
            app.Inverse.RowName = {};
            app.RESETButton = uibutton(app.InverseKinematicsNoDHUIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.AllValuesforAnglesmustbeenteredinDEGREEUnitONLYLabel = uilabel(app.InverseKinematicsNoDHUIFigure);
            app.AllValuesforAnglesmustbeenteredinDEGREEUnitONLYLabel.Text = 'All Values for Angles must be entered in DEGREE Unit ONLY';
            app.LinkDefinations = uipanel(app.InverseKinematicsNoDHUIFigure);
            app.LinkDefinations.Title = 'Enter Link Definations one by one';
            app.LinkNo = uieditfield(app.LinkDefinations, 'numeric');
            app.LinkNoLabel = uilabel(app.LinkDefinations);
            app.LinkNoLabel.Text = 'Link No.';
            app.NumberofLinksEditFieldLabel = uilabel(app.LinkDefinations);
            app.NumberofLinksEditFieldLabel.Text = 'Number of Links';
            app.TotalLinks = uieditfield(app.LinkDefinations, 'numeric');
            app.aInput = uieditfield(app.LinkDefinations, 'numeric');
            app.LengthoflinkLabel = uilabel(app.LinkDefinations);
            app.LengthoflinkLabel.Text = 'Length of link';
            app.AnglebetweentheZaxisoftheconsecutivelinksLabel = uilabel(app.LinkDefinations);
            app.AnglebetweentheZaxisoftheconsecutivelinksLabel.Text = 'Angle between the Z axis of the consecutive links';
            app.alphaInput = uieditfield(app.LinkDefinations, 'numeric');
            app.AnglebetweentheXaxisoftheconsecutivelinksLabel = uilabel(app.LinkDefinations);
            app.AnglebetweentheXaxisoftheconsecutivelinksLabel.Text = 'Angle between the X axis of the consecutive links';
            app.thetaInput = uieditfield(app.LinkDefinations, 'numeric');
            app.dInput = uieditfield(app.LinkDefinations, 'numeric');
            app.DistancebetweenjointsinZaxisofpreviousjointLabel = uilabel(app.LinkDefinations);
            app.DistancebetweenjointsinZaxisofpreviousjointLabel.Text = 'Distance between joints in Z axis of previous joint';
            app.PrismaticJointLimitsLabel = uilabel(app.LinkDefinations);
            app.PrismaticJointLimitsLabel.Text = 'Prismatic Joint Limits';
            app.Plimmin = uieditfield(app.LinkDefinations, 'numeric');
            app.Plimmax = uieditfield(app.LinkDefinations, 'numeric');
            app.MinLabel = uilabel(app.LinkDefinations);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.LinkDefinations);
            app.MaxLabel.Text = 'Max';
            app.DHUpdate = uibutton(app.LinkDefinations, 'push');
            app.DHUpdate.ButtonPushedFcn = createCallbackFcn(app, @DHUpdateButtonPushed, true);
            app.DHUpdate.Text = 'UPDATE';
            app.Linktype = uieditfield(app.LinkDefinations, 'text');
            app.TextArea = uitextarea(app.LinkDefinations);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Enter r for revolute and p for prismatic'};
            % Show the figure after all components are created
            app.InverseKinematicsNoDHUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Inverse_Kinematics_noDH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InverseKinematicsNoDHUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InverseKinematicsNoDHUIFigure)
        end
    end
end