classdef Trial1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DHTable                      matlab.ui.control.Table
        EnterLinkDefinationsonebyonePanel  matlab.ui.container.Panel
        LinkNo                       matlab.ui.control.NumericEditField
        thetaInput                   matlab.ui.control.NumericEditField
        dInput                       matlab.ui.control.NumericEditField
        aInput                       matlab.ui.control.NumericEditField
        alphaInput                   matlab.ui.control.NumericEditField
        DHUpdate                     matlab.ui.control.Button
        LinkNoLabel                  matlab.ui.control.Label
        ThetaLabel                   matlab.ui.control.Label
        dLabel                       matlab.ui.control.Label
        aLabel                       matlab.ui.control.Label
        alphaLabel                   matlab.ui.control.Label
        NumberofLinksEditFieldLabel  matlab.ui.control.Label
        TotalLinks                   matlab.ui.control.NumericEditField
        LinkType                     matlab.ui.control.DropDown
        INVERSEKINEMATICSLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        theta = [];
        a   =   [];
        d   =   [];
        alpha = [];
        type  = [];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run startup_rvc.m
        end

        % Callback function: DHTable, DHUpdate
        function DHUpdateButtonPushed(app, event)
          n = app.TotalLinks.Value;
            i = app.LinkNo.Value;
            app.theta(i) = app.thetaInput.Value;
            app.d(i) = app.dInput.Value;
            app.a(i) = app.aInput.Value;
            app.alpha(i) = app.alphaInput.Value;
            
            if i==n
                for i = 1:n
                    param(i,:) = [app.theta(i), app.d(i), app.a(i), app.alpha(i)];
                end
                app.DHTable.Data = param;
            
            for j = 1:n
                
            end


            end
                       
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DHTable = uitable(app.UIFigure);
            app.DHTable.ColumnName = {'Theta'; 'd'; 'a'; 'alpha'; 'Type'};
            app.DHTable.RowName = {''};
            app.DHTable.DisplayDataChangedFcn = createCallbackFcn(app, @DHUpdateButtonPushed, true);
            app.EnterLinkDefinationsonebyonePanel = uipanel(app.UIFigure);
            app.EnterLinkDefinationsonebyonePanel.Title = 'Enter Link Definations one by one';
            app.LinkNo = uieditfield(app.EnterLinkDefinationsonebyonePanel, 'numeric');
            app.thetaInput = uieditfield(app.EnterLinkDefinationsonebyonePanel, 'numeric');
            app.dInput = uieditfield(app.EnterLinkDefinationsonebyonePanel, 'numeric');
            app.aInput = uieditfield(app.EnterLinkDefinationsonebyonePanel, 'numeric');
            app.alphaInput = uieditfield(app.EnterLinkDefinationsonebyonePanel, 'numeric');
            app.DHUpdate = uibutton(app.EnterLinkDefinationsonebyonePanel, 'push');
            app.DHUpdate.ButtonPushedFcn = createCallbackFcn(app, @DHUpdateButtonPushed, true);
            app.DHUpdate.Text = 'Update';
            app.LinkNoLabel = uilabel(app.EnterLinkDefinationsonebyonePanel);
            app.LinkNoLabel.Text = 'Link No.';
            app.ThetaLabel = uilabel(app.EnterLinkDefinationsonebyonePanel);
            app.ThetaLabel.Text = 'Theta';
            app.dLabel = uilabel(app.EnterLinkDefinationsonebyonePanel);
            app.dLabel.Text = 'd';
            app.aLabel = uilabel(app.EnterLinkDefinationsonebyonePanel);
            app.aLabel.Text = 'a';
            app.alphaLabel = uilabel(app.EnterLinkDefinationsonebyonePanel);
            app.alphaLabel.Text = 'alpha';
            app.NumberofLinksEditFieldLabel = uilabel(app.EnterLinkDefinationsonebyonePanel);
            app.NumberofLinksEditFieldLabel.Text = 'Number of Links';
            app.TotalLinks = uieditfield(app.EnterLinkDefinationsonebyonePanel, 'numeric');
            app.LinkType = uidropdown(app.EnterLinkDefinationsonebyonePanel);
            app.LinkType.Items = {'Revolute', 'Prismatic'};
            app.LinkType.Value = 'Revolute';
            app.INVERSEKINEMATICSLabel = uilabel(app.UIFigure);
            app.INVERSEKINEMATICSLabel.Text = 'INVERSE KINEMATICS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Trial1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end